classdef FFT_Serial_Communication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout_all              matlab.ui.container.GridLayout
        Panel_right                 matlab.ui.container.Panel
        GridLayout_right            matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        Tab_theory                  matlab.ui.container.Tab
        GridLayout_theory           matlab.ui.container.GridLayout
        Tab_example                 matlab.ui.container.Tab
        GridLayout_example          matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout_fourier          matlab.ui.container.GridLayout
        Image_fourier               matlab.ui.control.Image
        Panel_html4                 matlab.ui.container.Panel
        GridLayout_html4            matlab.ui.container.GridLayout
        Button_html4                matlab.ui.control.Button
        Label_html4                 matlab.ui.control.Label
        Panel_htmls                 matlab.ui.container.Panel
        GridLayout_htmls            matlab.ui.container.GridLayout
        Panel_html3                 matlab.ui.container.Panel
        GridLayout_html3            matlab.ui.container.GridLayout
        Panel_html2                 matlab.ui.container.Panel
        GridLayout_html2            matlab.ui.container.GridLayout
        Panel_axes                  matlab.ui.container.Panel
        GridLayout_axes             matlab.ui.container.GridLayout
        UIAxes_3d                   matlab.ui.control.UIAxes
        UIAxes_freq                 matlab.ui.control.UIAxes
        UIAxes_time                 matlab.ui.control.UIAxes
        UIAxes_phase                matlab.ui.control.UIAxes
        Slider_example              matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        Tab_picture                 matlab.ui.container.Tab
        GridLayout_picture          matlab.ui.container.GridLayout
        GridLayout_re2              matlab.ui.container.GridLayout
        Button_re2                  matlab.ui.control.Button
        GridLayout_re1              matlab.ui.container.GridLayout
        Button_re1                  matlab.ui.control.Button
        GridLayout_fftOperate       matlab.ui.container.GridLayout
        Button_openPde              matlab.ui.control.Button
        Button_killPython           matlab.ui.control.Button
        Hyperlink_pythonDownload    matlab.ui.control.Hyperlink
        Button_processing           matlab.ui.control.Button
        DropDown_curveFitting       matlab.ui.control.DropDown
        Label_6                     matlab.ui.control.Label
        DropDown_clearOrbit         matlab.ui.control.DropDown
        Label_5                     matlab.ui.control.Label
        EditField_fourierThreshold  matlab.ui.control.NumericEditField
        EditField_5Label            matlab.ui.control.Label
        EditField_edgeSampleRate    matlab.ui.control.NumericEditField
        EditField_4Label            matlab.ui.control.Label
        EditField_rotationSpeed     matlab.ui.control.NumericEditField
        Label_4                     matlab.ui.control.Label
        Button_dynamicFFT           matlab.ui.control.Button
        Label_svgPaths              matlab.ui.control.Label
        Label_originalPic           matlab.ui.control.Label
        TextArea_ways               matlab.ui.control.TextArea
        Button_importPic            matlab.ui.control.Button
        Button_instruction          matlab.ui.control.Button
        Panel_svgPaths              matlab.ui.container.Panel
        GridLayout_svgPaths         matlab.ui.container.GridLayout
        Image_svgPaths              matlab.ui.control.Image
        UIAxes_edge                 matlab.ui.control.UIAxes
        Panel_originalPic           matlab.ui.container.Panel
        GridLayout_originalPic      matlab.ui.container.GridLayout
        Image_originalPic           matlab.ui.control.Image
        ButtonGroup_ways            matlab.ui.container.ButtonGroup
        Button_way2                 matlab.ui.control.RadioButton
        Button_way1                 matlab.ui.control.RadioButton
        Tab_audio                   matlab.ui.container.Tab
        GridLayout_audio            matlab.ui.container.GridLayout
        Button_instruPlay           matlab.ui.control.Button
        ButtonGroup_figure          matlab.ui.container.ButtonGroup
        Button_whole                matlab.ui.control.RadioButton
        Button_part                 matlab.ui.control.RadioButton
        ButtonGroup_instrument      matlab.ui.container.ButtonGroup
        Button_trumpet              matlab.ui.control.RadioButton
        Button_melodyorgan          matlab.ui.control.RadioButton
        Button_bowbells             matlab.ui.control.RadioButton
        Button_harpsichord          matlab.ui.control.RadioButton
        Button_glockenspiel         matlab.ui.control.RadioButton
        Button_marimba              matlab.ui.control.RadioButton
        Button_drums                matlab.ui.control.RadioButton
        Button_piano                matlab.ui.control.RadioButton
        Button_saxophone            matlab.ui.control.RadioButton
        Button_flute                matlab.ui.control.RadioButton
        Button_keyboard             matlab.ui.control.RadioButton
        Button_guitar               matlab.ui.control.RadioButton
        Button_bass                 matlab.ui.control.RadioButton
        Button_cello                matlab.ui.control.RadioButton
        Button_viola                matlab.ui.control.RadioButton
        Button_violin               matlab.ui.control.RadioButton
        Panel_strengthenVoice       matlab.ui.container.Panel
        GridLayout_strengthenVoice  matlab.ui.container.GridLayout
        Label_time                  matlab.ui.control.Label
        Button_voicePrinciple       matlab.ui.control.Button
        Button_stengthenVoice       matlab.ui.control.Button
        Slider_progress             matlab.ui.control.Slider
        Panel_musicInstrument       matlab.ui.container.Panel
        GridLayout_musicInstrument  matlab.ui.container.GridLayout
        UIAxes_musicInstrument      matlab.ui.control.UIAxes
        Panel_spectrum              matlab.ui.container.Panel
        GridLayout_spectrum         matlab.ui.container.GridLayout
        UIAxes_spectrum             matlab.ui.control.UIAxes
        Panel_timeWave              matlab.ui.container.Panel
        GridLayout_timeWave         matlab.ui.container.GridLayout
        UIAxes_timeWave             matlab.ui.control.UIAxes
        Button_nextMusic            matlab.ui.control.Button
        Button_preMusic             matlab.ui.control.Button
        Button_stopMusic            matlab.ui.control.Button
        Button_resumeMusic          matlab.ui.control.Button
        Button_pauseMusic           matlab.ui.control.Button
        Button_playMusic            matlab.ui.control.Button
        Button_importMusic          matlab.ui.control.Button
        ListBox_songs               matlab.ui.control.ListBox
        Tab_soundSignal             matlab.ui.container.Tab
        GridLayout_soundSignal      matlab.ui.container.GridLayout
        Panel_thresholdSound        matlab.ui.container.Panel
        GridLayout_thresholdSound   matlab.ui.container.GridLayout
        Slider_thresholdSound       matlab.ui.control.Slider
        Label_7                     matlab.ui.control.Label
        TextArea_soundSignal        matlab.ui.control.TextArea
        Panel_changeVoice           matlab.ui.container.Panel
        GridLayout_changeVoice      matlab.ui.container.GridLayout
        ButtonGroup_device          matlab.ui.container.ButtonGroup
        Button_devMicrophone        matlab.ui.control.RadioButton
        Button_devArduino           matlab.ui.control.RadioButton
        Button_changeVoicePlay      matlab.ui.control.Button
        Button_changeVoiceStop      matlab.ui.control.Button
        ButtonGroup_changeVoice     matlab.ui.container.ButtonGroup
        Button_robotVoice           matlab.ui.control.RadioButton
        Button_cartoonVoice         matlab.ui.control.RadioButton
        Button_maleVoice            matlab.ui.control.RadioButton
        Button_femaleVoice          matlab.ui.control.RadioButton
        UIAxes_voiceOriginal        matlab.ui.control.UIAxes
        UIAxes_voiceChanged         matlab.ui.control.UIAxes
        Panel_soundProButtons       matlab.ui.container.Panel
        GridLayout_soundProButtons  matlab.ui.container.GridLayout
        Lamp                        matlab.ui.control.Lamp
        Button_sStop                matlab.ui.control.Button
        Button_sPlay                matlab.ui.control.Button
        Button_sExport              matlab.ui.control.Button
        Button_sEnd                 matlab.ui.control.Button
        Button_sStart               matlab.ui.control.Button
        UITable_extractData         matlab.ui.control.Table
        Panel_arduino_bat           matlab.ui.container.Panel
        GridLayout_arduino_bat      matlab.ui.container.GridLayout
        Button_connectArduino       matlab.ui.control.Button
        DropDown_port               matlab.ui.control.DropDown
        Button_circuit              matlab.ui.control.Button
        Button_bat                  matlab.ui.control.Button
        UIAxes_soundWaveform        matlab.ui.control.UIAxes
        UIAxes_soundSpectrum        matlab.ui.control.UIAxes
        UIAxes_soundSilence         matlab.ui.control.UIAxes
        Tab_lightSignal             matlab.ui.container.Tab
        GridLayout_lightSignal      matlab.ui.container.GridLayout
        Panel_arduino_bat_2         matlab.ui.container.Panel
        GridLayout_arduino_bat_2    matlab.ui.container.GridLayout
        Button_connectArduino_2     matlab.ui.control.Button
        DropDown_port_2             matlab.ui.control.DropDown
        Button_circuit_2            matlab.ui.control.Button
        Button_bat_2                matlab.ui.control.Button
        TextArea_lightSignal        matlab.ui.control.TextArea
        Panel_lightProButtons       matlab.ui.container.Panel
        GridLayout_lightProButtons  matlab.ui.container.GridLayout
        Lamp_2                      matlab.ui.control.Lamp
        Button_lend                 matlab.ui.control.Button
        Button_lstart               matlab.ui.control.Button
        UITable_light               matlab.ui.control.Table
        UIAxes_lightWaveform        matlab.ui.control.UIAxes
        UIAxes_lightSpectrum        matlab.ui.control.UIAxes
        Tab_musicGame               matlab.ui.container.Tab
        GridLayout_musicGame        matlab.ui.container.GridLayout
        Panel_buttonsMG             matlab.ui.container.Panel
        GridLayout_buttonsMG        matlab.ui.container.GridLayout
        Button_playMG               matlab.ui.control.Button
        Slider_volume               matlab.ui.control.Slider
        Label_8                     matlab.ui.control.Label
        Label_score                 matlab.ui.control.Label
        Button_stopMG               matlab.ui.control.Button
        Button_startMG              matlab.ui.control.Button
        Button_analyzeMusic         matlab.ui.control.Button
        Button_importMusic_2        matlab.ui.control.Button
        TextArea_keyInstruction     matlab.ui.control.TextArea
        ListBox_songs_2             matlab.ui.control.ListBox
        UIAxes_game                 matlab.ui.control.UIAxes
        UIAxes_musicSpectrum        matlab.ui.control.UIAxes
        Tab_practice                matlab.ui.container.Tab
        GridLayout_practice         matlab.ui.container.GridLayout
        Tab_dataOutput              matlab.ui.container.Tab
        GridLayout_dataOutput       matlab.ui.container.GridLayout
        Panel_data                  matlab.ui.container.Panel
        GridLayout_data             matlab.ui.container.GridLayout
        Image_self                  matlab.ui.control.Image
        Button_exportData           matlab.ui.control.Button
        Tree_3                      matlab.ui.container.CheckBoxTree
        Node_musicGame              matlab.ui.container.TreeNode
        Node_mgSpectrum             matlab.ui.container.TreeNode
        Node_score                  matlab.ui.container.TreeNode
        Node_practice               matlab.ui.container.TreeNode
        Node_analysis               matlab.ui.container.TreeNode
        Tree_2                      matlab.ui.container.CheckBoxTree
        Node_soundArduino           matlab.ui.container.TreeNode
        Node_arduino                matlab.ui.container.TreeNode
        Node_arduinoPa              matlab.ui.container.TreeNode
        Node_threshold              matlab.ui.container.TreeNode
        Node_ardWave                matlab.ui.container.TreeNode
        Node_ardSpectrum            matlab.ui.container.TreeNode
        Node_silence                matlab.ui.container.TreeNode
        Node_origin                 matlab.ui.container.TreeNode
        Node_processed              matlab.ui.container.TreeNode
        Node_microphone             matlab.ui.container.TreeNode
        Node_micWave                matlab.ui.container.TreeNode
        Node_micSpectrum            matlab.ui.container.TreeNode
        Node_micOrigin              matlab.ui.container.TreeNode
        Node_micProcessed           matlab.ui.container.TreeNode
        Tree_1                      matlab.ui.container.CheckBoxTree
        Node_picFFT                 matlab.ui.container.TreeNode
        Node_way1                   matlab.ui.container.TreeNode
        Node_edge                   matlab.ui.container.TreeNode
        Node_way2                   matlab.ui.container.TreeNode
        Node_svg                    matlab.ui.container.TreeNode
        Node_picPa                  matlab.ui.container.TreeNode
        Node_fft                    matlab.ui.container.TreeNode
        Node_audioFFT               matlab.ui.container.TreeNode
        Node_audioWave              matlab.ui.container.TreeNode
        Node_audioSpectrum          matlab.ui.container.TreeNode
        Node_instrument             matlab.ui.container.TreeNode
        Node_ligntArduino           matlab.ui.container.TreeNode
        Node_ligntPa                matlab.ui.container.TreeNode
        Node_lightWave              matlab.ui.container.TreeNode
        Node_lightSpectrum          matlab.ui.container.TreeNode
        Label_title                 matlab.ui.control.Label
        Panel_left                  matlab.ui.container.Panel
        GridLayout_left             matlab.ui.container.GridLayout
        Button_dataOutput           matlab.ui.control.Button
        Button_practice             matlab.ui.control.Button
        Button_musicGame            matlab.ui.control.Button
        Button_lightSignal          matlab.ui.control.Button
        Button_soundSignal          matlab.ui.control.Button
        Button_audio                matlab.ui.control.Button
        Button_picture              matlab.ui.control.Button
        Button_example              matlab.ui.control.Button
        Button_theory               matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        Panel_up                    matlab.ui.container.Panel
        GridLayout_up               matlab.ui.container.GridLayout
        Label_warning               matlab.ui.control.Label
        Panel_tool                  matlab.ui.container.Panel
        GridLayout_tool             matlab.ui.container.GridLayout
        ToggleTool_python           matlab.ui.control.Image
        ToggleTool_language         matlab.ui.control.Image
        ToggleTool_arduino          matlab.ui.control.Image
        PushTool_audioInput         matlab.ui.control.Image
        PushTool_pictureInput       matlab.ui.control.Image
        Panel_sep4                  matlab.ui.container.Panel
        Panel_sep3                  matlab.ui.container.Panel
        Panel_sep2                  matlab.ui.container.Panel
        Panel_sep1                  matlab.ui.container.Panel
        Panel_menu                  matlab.ui.container.Panel
        GridLayout_menu             matlab.ui.container.GridLayout
        Menu_contact                matlab.ui.control.Button
        Menu_reference              matlab.ui.control.Button
        Menu_function               matlab.ui.control.Button
    end

    properties (Access = private) 
        % 通用
        timerSet % 计时器对象
        
        % 顶栏
        cmenu1 % 功能说明->上下文菜单
        cmenu2 % 参考资料->上下文菜单
        cmenu3 % 联系作者->上下文菜单
        functionDoc % 功能说明html
        funDocFig % 图形框
        linkDoc % 参考链接html
        linkDocFig % 图形框
        
        % 图像FFT
        picDoc % picFFT 说明文档
        picDocHtml1 % picFFT way1 html对象
        picDocHtml2 % picFFT way2 html对象
        pythonImported=0 % python已导入
        
        % 音频FFT 实时音游
        audioFileNames={} % 音频文件名
        audioPathName="" % 音频路径
        audioFileCount=0 % 音频序数
        SampleData=[] % 音频采样数据
        SampleDataInitial=[] % 音频初始采样数据
        Fs=-1 % 音频采样率
        CurrentSample % 当前采样数据
        Player={} % 音乐播放器对象
        FrameSize=1024 % FFT帧大小
        Window % 窗函数
        singleSampleData=[] % 单声道采样数据
        audioFFT % audioFFT说明文档
        audioDocHtml % audioFFT html对象
        figureType=1 % 1时绘制部分audioFFT图像，2时绘制整体
        lastClickTime=0 % 记录上一次点击的时间（用于乐器播放）
        instrumentName="" % 当前乐器

        % arduino声信号处理
        featureHistory % 特征记录表格
        updateCounter=0 % 更新计数器
        soundSignalStart=0 % 记录开始采集arduino声信号
        soundFirst=0 % 标记采集的第一个arduino声信号
        silenceFirst=0 % 标记采集的第一个arduino静音记号
        % 静音检测相关
        fsSoundArduino=5 % arduino声音传感器采样率（计时器回调及非音乐播放器的时间回调均通用）
        bufferSize=1024 % 缓冲区大小
        soundData=[] % 声音数据缓冲区，用于坐标区图像显示
        rawData=[] % 缓存数据
        timeStamps=datetime.empty(0,1) % 时间戳
        silenceRegions=zeros(0, 2) % 静音区段
        isRecording=false % 正在收集arduino信息
        audioSilence % 静音去除文件
        audioOriginal % 读取原文件
        player3={} % 播放器对象
        % 录音器相关
        RecObj % 录音器对象
        recAudioData=[] % 录制音频数据
        recFS=44100; % 采样率
        Nbits=16; % 位数
        Nchannels=1; % 通道数
        audioRec % 读取文件
        % 变声相关
        fsChange=-1 % 读取采样率
        yChange % 读取采样
        audio_modified=[] % 修改后采样数据
        playerTemp % 临时播放器变量
        audioChangeFile=[] % 读取转换后音频文件
        
        % arduino光信号处理
        lightSignalStart=0 % 光强信号记录开始
        lightData=[] % 存储光强数据
        lightTimeStamps=datetime.empty(0,1) % 存储时间戳
        dataLog=table() % 存储表格数据
        sampleCount=0 % 采样计数器
        lightStartTime % 开始时间戳
        dataTimer % 定时器
        lightFirst=0 % 标记第一个处理的光信号数据
        
        % 实时音游
        playerMG % 播放器对象
        notes  % 提取的音符序列
        gameTimer % 游戏计时器
        score=0 % 玩家得分
        currentNotes % 当前下落的音符
        keyMap % 键盘映射
        noteSounds  % 存储各音符音效
        hitPlayers % 音效播放器元胞数组
        volume=0.5 % 音量控制
        isGame=0 % 正在游戏标志
        
        % 数据导出
        % 各导出内容计数器
        cntPicFFT=0
        cntPicSvg2=0
        cntPicPa=0
        cntAudioIns=0
        cntAudioWave=0
        % arduino光信号处理表格
        dataTotalLight = table(...
            'Size', [0, 5], ... % 0行5列的空表格
            'VariableTypes', {'datetime', 'double', 'double', 'double', 'double'}, ...
            'VariableNames', {'Time', 'Original Value', 'Processed Value', 'Peak Frequency', 'Spectral Energy'});
        cntLight=0
        % arduino声信号处理表格
        dataTotalSound = table(...
            'Size', [0, 5], ... % 0行5列的空表格
            'VariableTypes', {'datetime', 'double', 'double', 'double', 'double'}, ...
            'VariableNames', {'Time', 'Average', 'Maximum', 'Dominant Frequency', 'Zero-crossing Rate'});
        cntSound=0
        cntSound2=0
        cntVoice1=0
        cntVoice2=0
        cntMG=0
    end
    
    properties (Access = public)
        % 通用
        appRoot = fileparts(mfilename('fullpath')) % 获取当前主文件的目录
        language="CH" % 语言选项（CH-中文 EN-英文）
        
        % 顶栏
        Menu_doc % 副菜单"说明文档"
        Menu_link % 副菜单"参考链接"
        Menu_mail % 副菜单"学校邮件"
        
        % FFT理论 简易图形示范
        html % html对象
        html2 % html对象
        html3 % html对象  
        html4 % html对象

        % 图像FFT
        picAlert1 % picFFT way1提示框
        picAlert2 % picFFT way2提示框
        packageExit1=0 % 是否已执行Python pip命令安装库（way2 svg）
        packageExit2=0 % 是否已执行Python pip命令安装库（way2 jpg/png）
        picFile % 图像路径
        picExit=0 % 是否已导入图片 
        copyPath % picFFT way1路径复制
        exePath1 % kruskal.exe路径
        exePath2 % fourier.exe路径
        pythonExePath % Python解释器路径
        pythonText % 显示Python解释器路径
        pythonAlert % 导入Python解释器提示框
        picChoose=0 % picFFT way2选择的是jpg/png(1)还是svg(2)
        
        % 音频FFT
        player2={} % 不同乐器播放对象   
        isEnhanceHumanVoice=false % 是否人声增强
        
        % arduino声信号处理 arduino光信号处理
        arduinoPort % 串口对象
        
        % 数据导出
        % 导出文件计数器
        cntPicSvg=0
        cntPicfft=0
    end

    methods (Access = private)
        %% 通用计时器函数
        % 创建计时器
        function createTimer(app)
            % 创建计时器对象
            app.timerSet=timer;
            app.timerSet.ExecutionMode='fixedRate';
            app.timerSet.Period=1/app.fsSoundArduino; % 根据arduino的loop()频率同步计时器周期
            app.timerSet.StartDelay=0.5; % 延迟 0.5 秒启动
            app.timerSet.TimerFcn=@(~,~) app.timerCallback(); % 回调函数
        end
        
        % 计时器回调函数
        function timerCallback(app)
            languageChange(app); % 调用语言切换函数
            app.TabGroupSelectionChanged(); % 调用table group回调函数
            app.updatePlot(); % 调用方波傅里叶变换展示函数
            app.ButtonGroup_waysSelectionChanged(); % 调用按钮选项组回调函数
            % arduino声信号处理：当前选择设备为arduino uno且已经开始声音传感器采样
            if app.soundSignalStart==1 && app.ButtonGroup_device.SelectedObject==app.Button_devArduino
                app.updateDisplay(); % 更新特征分析数据
            end
        end

        %% 简易图形示范
        % 方波傅里叶变换展示函数
        function updatePlot(app, ~)
            % 获取滑动条的值
            N = round(app.Slider_example.Value);

            % 定义方波的参数
            T = 2 * pi; % 周期
            t = linspace(-T, T, 1000); % 时间向量
            f = zeros(size(t)); % 初始化方波

            % 计算傅里叶级数
            for n = 1:2:N
                f = f + (4 / (pi * n)) * sin(2 * pi * n * t / T);
            end

            % 绘制时域图形
            plot(app.UIAxes_time, t, f, 'b', 'LineWidth', 1.5);
            if app.language=="CH"
                title(app.UIAxes_time, ['时域-叠加', num2str(N), '个谐波']);
                xlabel(app.UIAxes_time, '时间');
                ylabel(app.UIAxes_time, '幅值');
            else
                title(app.UIAxes_time, ['Time Domain-Superposition ', num2str(N), ' Harmonics']);
                xlabel(app.UIAxes_time, 'Time');
                ylabel(app.UIAxes_time, 'Amplitude');
            end

            grid(app.UIAxes_time, 'on'); % 继续绘制

            % 计算频域
            fft_result = fft(f); % 快速傅里叶变换
            fft_magnitude = abs(fft_result); % 幅值谱
            fft_phase = angle(fft_result); % 相位谱
            freq = linspace(0, 1, length(fft_magnitude)); % 归一化频率

            % 绘制频域图形
            stem(app.UIAxes_freq, freq(1:50), fft_magnitude(1:50), 'r', 'LineWidth', 1.5);
            
            if app.language=="CH"
                title(app.UIAxes_freq, ['频域-叠加 ', num2str(N), '个谐波']);
                xlabel(app.UIAxes_freq, '归一化频率');
                ylabel(app.UIAxes_freq, '幅值');
            else
                title(app.UIAxes_freq, ['Frequency Domain-Superposition ', num2str(N), ' Harmonics']);
                xlabel(app.UIAxes_freq, 'Normalized Frequency');
                ylabel(app.UIAxes_freq, 'Amplitude');
            end

            grid(app.UIAxes_freq, 'on'); % 继续绘制

            % 绘制相位谱
            stem(app.UIAxes_phase, freq(1:50), fft_phase(1:50), 'g', 'LineWidth', 1.5);

            if app.language=="CH"
                title(app.UIAxes_phase, ['相位谱-叠加', num2str(N), '个谐波']);
                xlabel(app.UIAxes_phase, '归一化频率');
                ylabel(app.UIAxes_phase, '相位');
            else
                title(app.UIAxes_phase, ['Phase spectrum-superposition ', num2str(N), ' Harmonics']);
                xlabel(app.UIAxes_phase, 'Normalized frequency');
                ylabel(app.UIAxes_phase, 'Phase');
            end

            grid(app.UIAxes_phase, 'on'); % 继续绘制

            % 绘制三维频域图
            stem3(app.UIAxes_3d, freq(1:50), fft_magnitude(1:50), fft_phase(1:50), 'm', 'LineWidth', 1.5);

            if app.language=="CH"
                title(app.UIAxes_3d, ['三维频域图-叠加 ', num2str(N), '个谐波']);
                xlabel(app.UIAxes_3d, '频率');
                ylabel(app.UIAxes_3d, '幅值');
                zlabel(app.UIAxes_3d, '相位');
            else
                title(app.UIAxes_3d, ['3D frequency domain diagram-overlay ', num2str(N), ' harmonics']);
                xlabel(app.UIAxes_3d, 'Frequency');
                ylabel(app.UIAxes_3d, 'Amplitude');
                zlabel(app.UIAxes_3d, 'Phase');
            end

            grid(app.UIAxes_3d, 'on'); % 继续绘制
        end
        
        %% 图像FFT
        % 读取data.in点列
        function points = readDataIn(~,filename)
            fid = fopen(filename, 'r');
            if fid == -1
                error('文件打开失败，请检查路径！');
            end
            
            % 读取第一行的点数
            n = fscanf(fid, '%d', 1);
            
            % 读取后续的坐标点
            data = fscanf(fid, '%f %f', [2, n])';
            fclose(fid);
            
            points = data; % 赋值点列
        end
        
        % 绘制轮廓/svg路径坐标区
        function plotPathInApp(app, points)
            % 清除坐标区并绘制路径
            cla(app.UIAxes_edge);
            plot(app.UIAxes_edge, points(:,1), points(:,2), '-o', ...
                 'LineWidth', 2, 'MarkerSize', 4, 'MarkerFaceColor', 'r');
            
            % 设置坐标区属性
            app.UIAxes_edge.XAxis.Visible = 'off';
            app.UIAxes_edge.YAxis.Visible = 'off';
            axis(app.UIAxes_edge, 'tight');  % 紧凑显示            
        end
        
        % 尝试自动检测Python解释器
        function detectPython(app)
            % 读取App预处理文件夹内的Python解释器路径
            content = strtrim(fileread(fullfile(app.appRoot,"pre","python","python_path.txt")));
            if isempty(content) || ~isfile(content) % 如果读取的解释器路径为空或该路径下不存在Python解释器
                try
                    [status, cmdout] = system('where python'); % 执行系统命令
                    
                    if status == 0
                        % 在Windows上找到Python
                        paths = strsplit(cmdout, '\n'); % 按换行符分割
                        app.pythonExePath = strtrim(paths{1}); % 取第一个显示的Python解释器路径
                        if app.language=="CH"
                            app.pythonText.Value = ['自动检测到Python解释器: ' app.pythonExePath];
                        else
                            app.pythonText.Value = ['Automatically detected Python interpreter: ' app.pythonExePath];
                        end
                        
                        % 将Python解释器路径存入App的预处理文件夹，便于下次使用App时自动读取
                        inputFile = fullfile(app.appRoot,"pre","python","python_path.txt");
                        fid = fopen(inputFile, 'w');
                        fprintf(fid, '%s', app.pythonExePath);
                        fclose(fid);
                    end
                catch
                    if app.language=="CH"
                        app.pythonText.Value = '自动检测Python解释器时出错';
                    else
                        app.pythonText.Value = 'Error while autodetecting Python interpreter';
                    end
                end
            else % Python解释器有效，直接显示信息
                app.pythonExePath=content;
                if app.language=="CH"
                    app.pythonText.Value = ['自动检测到Python解释器: ' app.pythonExePath];
                else
                    app.pythonText.Value = ['Automatically detected Python interpreter: ' app.pythonExePath];
                end
            end
        end
        
        % 创建导入Python解释器提示框
        function importPythonAlert(app)
            delete(app.pythonAlert);  % 删除旧提示框
            
            alertFig = uifigure('Name', 'Python select');
            parentPos = app.UIFigure.Position;
            initWidth = max(200, parentPos(3)*0.4);
            initHeight = max(150, parentPos(4)*0.4);
            alertFig.Position = [...
                parentPos(1)+(parentPos(3)-initWidth)/2, ...
                parentPos(2)+(parentPos(4)-initHeight)/2, ...
                initWidth, initHeight];
            app.pythonAlert=alertFig;
        
            % 创建网格布局容器
            grid = uigridlayout(alertFig);
            grid.RowHeight = {'1x', '4x'};  
            grid.ColumnWidth = {'1x'};  
            grid.Padding = [2 2 2 2];          
            grid.RowSpacing = 2;                 
            grid.ColumnSpacing = 2;
            
            % 创建按钮组件
            button=uibutton(grid);
            if app.language=="CH"
                button.Text = '选择Python解释器'; 
                button.FontName = "楷体";
            else
                button.Text = 'Select Python Interpreter'; 
                button.FontName = "Segoe UI";
            end
            
            % 创建文本区域组件
            app.pythonText = uitextarea(grid);

            app.pythonText.Layout.Row = 2;
            app.pythonText.Layout.Column = 1;
            detectPython(app); % 检测Python解释器路径
            
            % 按钮组件参数
            button.FontSize = 16;
            button.FontWeight = 'bold';
            button.HorizontalAlignment = 'center';
            button.WordWrap="on";
            button.Layout.Row = 1;
            button.Layout.Column = 1;
            button.ButtonPushedFcn = @(src, event) selectPythonButtonPushed(app); % 选择Python解释器回调
        end
        
        % 手动选择Python解释器
        function selectPythonButtonPushed(app)
            % 打开文件选择对话框
            if app.language=="CH"
                [file, path] = uigetfile({'*.exe', 'Python解释器 (python.exe)'}, ...
                                          '选择Python解释器');
                if isequal(file, 0)
                    app.pythonText.Value = '用户取消选择';
                else
                    app.pythonExePath = fullfile(path, file);
                    app.pythonText.Value= ['已选择Python解释器: ' app.pythonExePath];
                    
                    % 将Python解释器路径存入App的预处理文件夹，便于下次使用App时自动读取
                    inputFile = fullfile(app.appRoot,"pre","python","python_path.txt");
                    fid = fopen(inputFile, 'w');
                    fprintf(fid, '%s', app.pythonExePath);
                    fclose(fid);
                end
            else
                [file, path] = uigetfile({'*.exe', 'Python interpreter(python.exe)'}, ...
                                          'select Python interpreter');
                if isequal(file, 0)
                    app.pythonText.Value = 'User deselects';
                else
                    app.pythonExePath = fullfile(path, file);
                    app.pythonText.Value = ['Python interpreter selected: ' app.pythonExePath];
                    
                    % 将Python解释器路径存入App的预处理文件夹，便于下次使用App时自动读取
                    inputFile = fullfile(app.appRoot,"pre","python","python_path.txt");
                    fid = fopen(inputFile, 'w');
                    fprintf(fid, '%s', app.pythonExePath);
                    fclose(fid);
                end
            end
        end
        
        %% 音频FFT
        % 读取音乐数据并初始化播放数据
        function readMusicDataAndInit(app, filename)
            fullFilePath=[app.audioPathName, filename]; % 生成音乐文件路径名+文件名
            [sampleData, fs]=audioread(fullFilePath); % 读入音频文件数据
            app.SampleDataInitial=sampleData; % 存储原始音频文件采样数据
            app.SampleData=sampleData; % 存储播放数据
            app.Fs=fs; % 存储采样频率
            app.CurrentSample=1; % 初始化播放器的播放位置
            
            if app.TabGroup.SelectedTab==app.Tab_audio
                app.Window=hamming(app.FrameSize); % 初始化窗函数

                if size(app.SampleData, 2) == 2 % 如果音频文件为双声道（采样数据为2维矩阵）
                    app.singleSampleData = app.SampleData(:, 1); % 取单声道
                else % 如果音频文件为单声道
                    app.singleSampleData = app.SampleData;
                end
            end                       
        end

        % 初始化音乐播放器
        function initPlayer(app)     
            % 人声增强
            if app.isEnhanceHumanVoice==true % 标记增强人声
                [app.SampleData,~]=enhanceVoice(app.SampleData,app.Fs);
            else
                app.SampleData=app.SampleDataInitial; % 赋值初始采样数据
            end
            
            % 播放数据非空时，实例化audioplayer对象
            if(~isempty(app.SampleData))
                app.Player=audioplayer(app.SampleData, app.Fs); % 实例化audioplayer对象
                app.Player.TimerFcn=@(~,~) Player_timeFcn(app); % 定义Timer回调函数
                app.Player.TimerPeriod=0.2; % 定义Timer回调周期
            else
                app.Player={}; % audioplayer对象赋空
            end 
        end
        
        % 播放器回调函数
        function Player_timeFcn(app)
            % 1.根据当前播放位置，设定进度条显示设置
            currentSample=app.Player.CurrentSample; % 当前播放到的数据点
            totalSamples=app.Player.TotalSamples; % 音乐文件总数据点数
            app.Slider_progress.Value=currentSample/(totalSamples+1); % 设定进度条当前值

            % 2.根据当前播放位置，设定文本标签显示效果
            % （1）获取当前播放位置显示文本
            currentSec=currentSample/app.Fs; % 当前播放到的时刻（单位：秒）
            [tHourStrCur,tMinStrCur,tSecStrCur]=sec2HourMinSec(currentSec); % 转换为时分秒
            currentStr=[tHourStrCur,':',tMinStrCur,':',tSecStrCur]; % 时分秒字符串
            % （2）获取总时长显示文本
            totalSec=totalSamples/app.Fs; % 音乐文件总时长
            [tHourStrTotal,tMinStrTotal,tSecStrTotal]=sec2HourMinSec(totalSec); % 转换为时分秒
            totalStr=[tHourStrTotal,':',tMinStrTotal,':',tSecStrTotal]; % 时分秒字符串
            % （3）时间进度标签显示
            timeLabelStr=[currentStr,' / ',totalStr]; % 时间进度标签文本
            app.Label_time.Text=timeLabelStr; % 显示时间进度标签
            
            % 3.时域波形显示
            % 获得总的数据
            tAll=(1:totalSamples)/app.Fs; % 从开始到结束所有时刻点
            tAll=tAll'; % 行向量变列向量
            xAll=app.SampleData(:,1); % 从开始到结束单通道中所有音频数据
            % 获得当前播放位置附近的显示区间的数据
            duraDisplay=2; % 当前播放位置附近显示的时长
            lengthDisplay=round(app.Fs*duraDisplay); % 当前播放位置附近显示的数据点数
            if currentSample+lengthDisplay-1<=totalSamples % 正常情况
                idxStart=currentSample; % 获得起始索引位置
            else % 显示区间段最后超过总数据点数，防止越界
                idxStart=totalSamples-lengthDisplay+1; % 获得起始索引位置
            end
            idxEnd=idxStart+lengthDisplay-1; % 获得显示的结束索引位置
            tDisplay=tAll(idxStart:idxEnd,1);
            xDisplay=xAll(idxStart:idxEnd,1);
            if app.figureType==1 % 绘制类型为当前播放
                plot(app.UIAxes_timeWave,tDisplay,xDisplay,'-r') % 绘制当前播放附近的图
            else % 绘制类型为总图
                plot(app.UIAxes_timeWave,tAll,xAll,'-k') % 绘制总图
                hold(app.UIAxes_timeWave,'on') % 可叠加绘制开启
                plot(app.UIAxes_timeWave,tDisplay,xDisplay,'-r') % 绘制当前播放位置附近的图
                hold(app.UIAxes_timeWave,'off') % 可叠加绘制关闭
            end
            
            app.UIAxes_timeWave.YLim = [-1.2, 1.2]; % 纵轴 
            app.UIAxes_timeWave.YTick = -1.2:0.4:1.2; % 纵轴刻度

            % 4.更新频谱图
            updateSpectrum(app);

            drawnow limitrate; % 高性能刷新
        end
        
        % 动态更新频谱
        function updateSpectrum(app)   
            currentSample = app.Player.CurrentSample; % 获取当前采样数据
            if currentSample + app.FrameSize - 1 > length(app.singleSampleData) % 超出采样数据长度
                return;
            end
            
            % 提取当前帧并加窗
            frame = app.singleSampleData(currentSample : currentSample + app.FrameSize - 1);
            frameWindowed = frame .* app.Window;
            
            % 计算FFT
            spectrum = fft(frameWindowed);
            magnitude = abs(spectrum(1:app.FrameSize/2 + 1));
            frequencies = linspace(0, app.Fs/2, app.FrameSize/2 + 1);

             % 确保帧非空且无NaN/Inf
            if isempty(frame) || any(isnan(frame)) || any(isinf(frame))
                return;
            end
            
            % 更新频谱图
            plot(app.UIAxes_spectrum, frequencies, magnitude, '-r');
            app.UIAxes_spectrum.XLabel.String = 'Frequency (Hz)';
            app.UIAxes_spectrum.YLabel.String = 'Magnitude';
            app.UIAxes_spectrum.XLim = [0, 5000]; % 限制频率范围
            app.UIAxes_spectrum.XTick = 0:1000:5000; % 横轴刻度
            app.UIAxes_spectrum.YLim = [0, 20]; % 纵轴自动缩放（或固定值） 
            app.UIAxes_spectrum.YTick = 0:5:20; % 纵轴刻度
            
            if app.figureType==0 % 绘制类型为总图
                % 计算整首音频的平均频谱（分帧后平均）
                frameSize = app.FrameSize;
                numFrames = floor(length(app.singleSampleData) / frameSize);
                magnitudeSum = zeros(frameSize/2 + 1, 1);
                
                % 加窗操作、FFT操作
                for i = 1:numFrames
                    startSample = (i-1) * frameSize + 1;
                    frame = app.singleSampleData(startSample : startSample + frameSize - 1);
                    frameWindowed = frame .* app.Window;
                    spectrum = fft(frameWindowed);
                    magnitudeSum = magnitudeSum + abs(spectrum(1:frameSize/2 + 1));
                end
                
                % 计算平均幅度
                magnitude2 = magnitudeSum / numFrames;
                frequencies2 = linspace(0, app.Fs/2, frameSize/2 + 1);
                
                % 绘制平均频谱
                hold(app.UIAxes_spectrum,'on') % 可叠加绘制开启
                plot(app.UIAxes_spectrum,frequencies2,magnitude2,'-k') % 绘制总图
                hold(app.UIAxes_spectrum,'off') % 可叠加绘制关闭
            end
        end
        
        %% arduino声信号处理
        % 双击判定
        function doubleClick=buttonClicked(app)
            % 获取当前时间
            currentTime = datetime('now');
            currentTime = posixtime(currentTime); % 转换为时间戳（秒）
        
            % 计算时间间隔
            timeInterval = currentTime - app.lastClickTime;
        
            % 判断是否为双击
            if timeInterval < 0.5 % 双击时间间隔阈值（0.5 秒）
               doubleClick=true;
            else
               doubleClick=false;
            end
        
            % 更新上一次点击时间
            app.lastClickTime = currentTime;
        end
        
        % 获取可用端口
        function setupPortList(app)
            ports=serialportlist("available"); % 获取可用端口列表
            if isempty(ports)
                if app.language=="CH"
                    ports = "无可用端口";
                else
                    ports = "No available ports";
                end
            end
            app.DropDown_port.Items = ports; % 下拉框显示
            app.DropDown_port_2.Items = ports; % 下拉框显示
        end

        % 实时更新显示（arduino声信号）
        function updateDisplay(app)
            if app.soundFirst==0 % App第一个采样标志
                flush(app.arduinoPort, "input"); % 清空输入缓冲区
                app.soundFirst=app.soundFirst+1;
            end
            
            try
                % 读取数据
                data = readline(app.arduinoPort); % 读取串口数据
        
                % 解析静音标记
                if contains(data, "SILENCE_START:")
                    if app.silenceFirst==0 % 如果清除缓冲区后的第一个静音标记为SILENCE_START
                        app.silenceFirst=app.silenceFirst+1; % 更新静音标记
                    end
                    
                    startTime = str2double(extractAfter(data, ":")) / 1000; % 转换为秒
                    app.silenceRegions(end+1, 1) = startTime;
                    app.silenceRegions(end, 2) = -1; % 占位静音区段矩阵第2列，防止坐标区绘制时缺少第2列数据
                    app.Lamp.Color = 'red';
                    
                elseif contains(data, "SILENCE_END:")
                    if app.silenceFirst==0 % 如果清除缓冲区后的第一个静音标记为SILENCE_END
                        app.silenceRegions(end+1, 1) = 0;
                        app.silenceFirst=app.silenceFirst+1; % 更新静音标记
                    end
                    
                    endTime = str2double(extractAfter(data, ":")) / 1000;
                    if ~isempty(app.silenceRegions)
                        app.silenceRegions(end, 2) = endTime;
                        
                        % 检查静音持续时间是否满足最小要求
                        duration = endTime - app.silenceRegions(end, 1);
                        if duration < 1
                            app.silenceRegions(end,:) = []; % 移除短时静音
                        end
                    end
                    app.Lamp.Color = 'green';
                    
                % 解析波形数据    
                elseif contains(data, "DATA:")
                    newValue = str2double(extractAfter(data, ":"));
                    if ~isnan(newValue)
                        app.rawData(end+1) = newValue; % 更新缓冲区
                        app.timeStamps(end+1) = datetime('now'); % 更新时间戳

                        updateWaveform(app); % 更新波形显示
                        
                        % 更新数据缓冲区                       
                        app.soundData = [app.soundData(2:end); newValue];
                        
                        % 特征提取
                        features = app.extractFeatures(app.soundData);
                        
                        % 更新表格
                        app.updateTable(features);
                        
                        % 更新图形
                        app.updatePlots(features);
                        
                        drawnow limitrate; % 立即更新界面
                    end
                end
            catch ME % 错误信息提示
                if app.language=="CH"
                    errordlg(['运行时错误: ' ME.message], '系统错误');
                else
                    errordlg(['Erroe when running: ' ME.message], 'System Error');
                end
            end
        end

        % 特征提取函数
        function features = extractFeatures(app, data)
            validData = data(data ~= 0);  % 删除缓冲区中为0的项
    
            % 时域特征（仅基于非零数据）
            if ~isempty(validData)
                % 时域特征
                features.mean = mean(validData);
                features.max  = max(validData);
                features.min  = min(validData);
                
                % 频域特征
                [f, P1] = app.computeSpectrum(validData);
                [~, idx] = max(P1);
                features.dominantFreq = f(idx);
                
                % 过零率
                features.zcr = sum(diff(sign(validData - mean(validData))) ~= 0)/length(validData);
                
                % 事件检测
                features.peakCount = sum(validData > app.Slider_thresholdSound.Value);
            else
                % 处理全零数据的情况
                features.mean = 0;
                features.max = 0;
                features.min = 0;
                features.zcr = 0;
                features.peakCount = 0;
            end    
        end
        
        % 频谱计算
        function [f, P1] = computeSpectrum(app, data)
            L = length(data);
            Y = fft(data - mean(data)); % FFT处理
            P2 = abs(Y/L);
            P1 = P2(1:floor(L/2)+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = app.fsSoundArduino*(0:(floor(L/2)))/L;
        end
        
        % 更新表格
        function updateTable(app, features)
            app.updateCounter = app.updateCounter + 1; % 更新采样计数器
            if mod(app.updateCounter, 5) == 0 % 当前计数为5的倍数时更新表格（避免更新过快致MATLAB崩溃）
                % 创建新行
                newRow = {datetime('now'),... % 时间戳
                         features.mean,... % 均值
                         features.max,... % 峰值
                         features.dominantFreq,... % 主频
                         features.zcr}; % 过零率
                app.featureHistory = [app.featureHistory; newRow]; % 更新特征记录表格

                newTable = cell2table(newRow, 'VariableNames', app.dataTotalSound.Properties.VariableNames);
                app.dataTotalSound = [app.dataTotalSound; newTable];  % 拼接表格
                
                % 保持表格行数
                if height(app.featureHistory) > 20
                    app.featureHistory(1,:) = [];
                end

                app.UITable_extractData.Data = app.featureHistory; % 更新表格显示
                scroll(app.UITable_extractData, 'bottom'); % 可下拉至底部
            end
        end
        
        % 更新时域波形及频谱图
        function updatePlots(app, features)
            % 波形图
            cla(app.UIAxes_soundWaveform); % 清除之前图像
            plot(app.UIAxes_soundWaveform, app.soundData, 'b', 'LineWidth', 1.5);
            hold(app.UIAxes_soundWaveform, 'on'); % 继续绘制
            plot(app.UIAxes_soundWaveform,...
                find(app.soundData > app.Slider_thresholdSound.Value),...
                app.soundData(app.soundData > app.Slider_thresholdSound.Value),...
                'ro', 'MarkerSize', 4);
            yline(app.UIAxes_soundWaveform, app.Slider_thresholdSound.Value,...
                '--', 'Threshold', 'Color', [0.5 0 0]);
            if app.language=="CH"
                title(app.UIAxes_soundWaveform, sprintf('事件计数: %d', features.peakCount));
            else
                title(app.UIAxes_soundWaveform, sprintf('Events count: %d', features.peakCount));
            end
            
            % 频谱图
            [f, P1] = app.computeSpectrum(app.soundData); % 频谱计算
            semilogy(app.UIAxes_soundSpectrum, f, P1, 'r', 'LineWidth', 1.5); % 绘制半对数坐标图
            if app.language=="CH"
                title(app.UIAxes_soundSpectrum,...
                    sprintf('主频: %.1f Hz | 过零率: %.2f',...
                    features.dominantFreq, features.zcr));
            else
                title(app.UIAxes_soundSpectrum,...
                sprintf('Dominant freq: %.1f Hz | Zero-crossing rate: %.2f',...
                features.dominantFreq, features.zcr));
            end
        end
        
        % 静音检测图像更新函数
        function updateWaveform(app)
            t = (0:length(app.rawData)-1)/app.fsSoundArduino;
            plot(app.UIAxes_soundSilence, t, app.rawData, 'b');
            hold(app.UIAxes_soundSilence, 'on');
            
            % 标记静音区间
            for i = 1:size(app.silenceRegions, 1)
                if app.silenceRegions(i,2) ~= -1
                    x = [app.silenceRegions(i,1), app.silenceRegions(i,2)];
                    y = [0, 0];
                    plot(app.UIAxes_soundSilence, x, y, 'r', 'LineWidth', 3);
                end
            end
            
            hold(app.UIAxes_soundSilence, 'off');
            drawnow limitrate;
        end
        
        % 转机器声
        function change_robot(app)
            % 1.读取音频
            if app.ButtonGroup_device.SelectedObject == app.Button_devArduino
                [app.yChange, app.fsChange] = audioread(app.audioOriginal);
            else
                [app.yChange, app.fsChange] = audioread(app.audioRec);
            end
            audio = mean(app.yChange, 2);  % 转为单声道
            
            % 2.FFT频谱分析
            N = length(audio);
            Y = fft(audio);
            
            % 3.机器人声处理（相位扰动 + 谐波增强）
            % 相位扰动
            phase_noise = 1 * pi * (rand(N,1) - 0.5);  % 有限随机扰动
            Y_shifted = abs(Y) .* exp(1i * (angle(Y) + phase_noise));
            
            % 谐波增强
            f0 = 100;  % 基频估计（实际需动态检测）
            harmonic_mask = zeros(N, 1);
            for h = 1:5
                f_low = max(1, floor((h*f0 - 20) * N / app.fsChange));
                f_high = min(N, ceil((h*f0 + 20) * N / app.fsChange));
                harmonic_mask(f_low:f_high) = 1;
            end
            Y_shifted = Y_shifted .* (1 + 2*harmonic_mask);
            
            % 4.IFFT恢复时域信号
            [~, env] = envelope(audio, 100, 'rms');  % 原始包络
            app.audio_modified = real(ifft(Y_shifted)) .* env;
            app.audio_modified = app.audio_modified / max(abs(app.audio_modified));
            
            % 5.保存文件
            timestamp = datetime("now","Format","dd-MM-uuuu-HH-mm-ss");
            filename = ['robot_' char(timestamp) '.wav'];
            app.audioChangeFile = fullfile(app.appRoot, "saved_audios", filename);
            audiowrite(app.audioChangeFile, app.audio_modified, app.fsChange);
        end

        % 转男声
        function change_male(app)
            % 1.读取音频
            if app.ButtonGroup_device.SelectedObject==app.Button_devArduino
                [app.yChange, app.fsChange] = audioread(app.audioOriginal);
            else
                [app.yChange, app.fsChange] = audioread(app.audioRec);
            end
            audio = mean(app.yChange, 2);  % 转为单声道
            
            % 2.FFT频谱分析
            N = length(audio);
            Y = fft(audio);
            
            % 3.男声处理（降低音高 + 共振峰调整）
            pitch_shift_factor = 0.8;  % 降低音高
            Y_shifted = interp1(1:N, Y, (1:N)/pitch_shift_factor, 'linear', 0);
            
            % 4.IFFT恢复时域信号
            app.audio_modified = real(ifft(Y_shifted));
            app.audio_modified = app.audio_modified / max(abs(app.audio_modified));  % 归一化

            app.audio_modified = resample(app.audio_modified, length(audio), length(app.audio_modified));
            
            % 5.保存文件
            timestamp = datetime("now","Format","dd-MM-uuuu-HH-mm-ss");
            filename = ['male_' char(timestamp) '.wav'];
            app.audioChangeFile=fullfile(app.appRoot,"saved_audios",filename);
            audiowrite(app.audioChangeFile, app.audio_modified, app.fsChange);
        end

        % 转女声
        function change_female(app)
            % 1.读取音频
            if app.ButtonGroup_device.SelectedObject==app.Button_devArduino
                [app.yChange, app.fsChange] = audioread(app.audioOriginal);
            else
                [app.yChange, app.fsChange] = audioread(app.audioRec);
            end
            audio = mean(app.yChange, 2);  % 转为单声道
            
            % 2.FFT频谱分析
            N = length(audio);
            Y = fft(audio);
            
            % 3.女声处理（提高音高 + 共振峰调整）
            pitch_shift_factor = 1.2;  % 提高音高
            Y_shifted = interp1(1:N, Y, (1:N)/pitch_shift_factor, 'linear', 0);
            
            % 4.IFFT恢复时域信号
            app.audio_modified = real(ifft(Y_shifted));
            app.audio_modified = app.audio_modified / max(abs(app.audio_modified));  % 归一化
            
            % 5.保存文件
            timestamp = datetime("now","Format","dd-MM-uuuu-HH-mm-ss");
            filename = ['female_' char(timestamp) '.wav'];
            app.audioChangeFile=fullfile(app.appRoot,"saved_audios",filename);
            audiowrite(app.audioChangeFile, app.audio_modified, app.fsChange);
        end
        
        % 转卡通
        function change_cartoon(app)
            % 1.读取音频
            if app.ButtonGroup_device.SelectedObject==app.Button_devArduino
                [app.yChange, app.fsChange] = audioread(app.audioOriginal);
            else
                [app.yChange, app.fsChange] = audioread(app.audioRec);
            end
            audio = mean(app.yChange, 2);  % 转为单声道
            
            % 2.FFT频谱分析
            N = length(audio);
            Y = fft(audio);
            
            % 3.卡通声处理（非线性压缩 + 高频增强）
            % 非线性压缩（增强谐波）
            Y_shifted = Y .* (1 + 0.5*abs(Y)/max(abs(Y)));
            
            % 高频增强
            high_freq_boost = linspace(1, 3, N/2)';  % 高频增益逐渐增强
            Y_shifted(1:N/2) = Y_shifted(1:N/2) .* high_freq_boost;
            Y_shifted(N/2+1:end) = Y_shifted(N/2+1:end) .* flip(high_freq_boost);
            
            % 4.IFFT恢复时域信号
            app.audio_modified = real(ifft(Y_shifted));
            app.audio_modified = app.audio_modified / max(abs(app.audio_modified));  % 归一化
            
            % 5.保存文件
            timestamp = datetime("now","Format","dd-MM-uuuu-HH-mm-ss");
            filename = ['cartoon_' char(timestamp) '.wav'];
            app.audioChangeFile=fullfile(app.appRoot,"saved_audios",filename);
            audiowrite(app.audioChangeFile, app.audio_modified, app.fsChange);
        end
        
        %% arduino光信号处理
        % 实时更新显示（arduino光信号）
        function updateLight(app)
            if app.lightSignalStart==1 % 开始光信号采样
                if app.lightFirst==0 % 开始采样后处理第一个光信号
                    flush(app.arduinoPort, "input"); % 清空输入缓冲区
                    app.lightFirst=app.lightFirst+1;
                end
                newData = readline(app.arduinoPort); % 读取一行数据
                Value = str2double(newData); % 数据转换为double类型
                
                if ~isnan(Value)
                    % 更新计数器
                    app.sampleCount = app.sampleCount + 1;
                    currentTime = datetime('now');
                    differ=seconds(currentTime-app.lightStartTime);
                    app.lightTimeStamps = [app.lightTimeStamps(2:end); differ];
                    
                    A = 1.2e6; % 拟合参数A
                    B = 0.85; % 拟合参数B
                    voltage = Value * (5.0 / 1023.0);  % 转换为电压（5V供电）
                    resistance = 1e4 * voltage / (5.0 - voltage);  % 计算阻值（与10kΩ分压）           
                    newValue = power(A / resistance, 1.0 / B);  % 计算光强
                    
                    % 更新数据缓冲区（先进先出）
                    app.lightData = [app.lightData(2:end); newValue];
                    
                    % 计算 FFT 和频谱能量
                    N = length(app.lightData);
                    fftResult = abs(fft(app.lightData .* hann(N)));
                    fftResult = fftResult(1:N/2); % 单边谱
                    freqAxis = (0:N/2-1) * (app.fsSoundArduino / N);
                    [~, maxIdx] = max(fftResult(2:end));
                    peakFreq = freqAxis(maxIdx+1);
                    spectralEnergy = sum(fftResult.^2); % 频谱总能量
                    
                    % 更新表格数据（保留最新 15 条记录）
                    newRow = {currentTime, Value, newValue, peakFreq, spectralEnergy};
                    
                    newTable = cell2table(newRow, 'VariableNames', app.dataTotalLight.Properties.VariableNames);
                    app.dataTotalLight = [app.dataTotalLight; newTable];  % 拼接表格
                    
                    if height(app.dataLog) < 15
                        app.dataLog = [app.dataLog; newRow];
                    else
                        app.dataLog = [app.dataLog(2:end, :); newRow];
                    end
                    app.UITable_light.Data = app.dataLog; % 更新表格显示
                    scroll(app.UITable_light, 'bottom'); % 可以下拉表格
                    
                    % 更新图形
                    plot(app.UIAxes_lightWaveform, app.lightTimeStamps, app.lightData, 'b');
                    plot(app.UIAxes_lightSpectrum, freqAxis, fftResult, 'r');                                   
                end
                drawnow limitrate; % 立即更新
            end
        end
        
        %% 实时音游
        % 为每个音符创建简短的音效 (正弦波)
        function loadNoteSounds(app)
            % 为每个音符创建简短的音效 (正弦波)
            duration = 0.2; % 音效时长(秒)
            t = (0:1/app.Fs:duration)'; % 列向量确保尺寸一致
            
            % 确保时间点数量是整数
            numPoints = length(t);
            attackPoints = floor(numPoints/10);
            decayPoints = numPoints - attackPoints;
            
            % 音符频率 (C4到B4)
            noteFreqs = [261.63, 293.66, 329.63, 349.23, 392.00, 440.00, 493.88];
            
            app.noteSounds = cell(1,7);
            for i = 1:7
                % 创建带包络的音效
                sound = sin(2*pi*noteFreqs(i)*t);
                
                % 生成包络线（前10%渐强，后90%渐弱）
                envelope = [linspace(0,1,attackPoints)'; 
                           linspace(1,0.7,decayPoints)'];
                
                % 确保尺寸匹配
                if length(envelope) ~= length(sound)
                    envelope = envelope(1:length(sound));
                end
                
                sound = sound .* envelope;
                app.noteSounds{i} = sound;
            end
        end
        
        % 使用FFT分析音频并提取音符
        function analyzeAudio(app)
            if isempty(app.SampleData)
                if app.language=="CH"
                    uialert(app.UIFigure, '请先导入音频文件', '错误');
                else
                    uialert(app.UIFigure, 'Please import audio files first', 'error');
                end
                return;
            end
            
            % 只使用单声道
            if size(app.SampleData,2) > 1 % 采样数据矩阵大于1维
                monoAudio = mean(app.SampleData, 2);
            else
                monoAudio = app.SampleData;
            end
            
            % 分帧处理
            frameSize = round(0.1 * app.Fs); % 100ms一帧
            numFrames = floor(length(monoAudio)/frameSize);
            frequencies = zeros(numFrames,1);
            
            % 对每帧进行FFT分析
            for i = 1:numFrames
                frame = monoAudio((i-1)*frameSize+1 : i*frameSize);
                Y = fft(frame);
                P2 = abs(Y/frameSize);
                P1 = P2(1:frameSize/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                
                f = app.Fs*(0:(frameSize/2))/frameSize;
                [~, idx] = max(P1);
                frequencies(i) = f(idx);
            end
            
            % 将频率转换为音符
            app.notes = frequencyToNote(app,frequencies);
            
            % 显示频谱
            plot(app.UIAxes_musicSpectrum, frequencies);
        end

        % 初始化游戏
        function startGame(app)
            if app.isGame==1
                cla(app.UIAxes_game);
                axis(app.UIAxes_game, [0 10 0 10]);
                app.UIAxes_game.XTick = [];
                app.UIAxes_game.YTick = [];
                app.UIAxes_game.Box = 'on';
                title(app.UIAxes_game, 'Music Game - Hit the notes!');
                
                % 初始化键盘映射 (数字键1-7对应音符)
                app.keyMap = containers.Map;
                app.keyMap('1') = 1; % C
                app.keyMap('2') = 2; % D
                app.keyMap('3') = 3; % E
                app.keyMap('4') = 4; % F
                app.keyMap('5') = 5; % G
                app.keyMap('6') = 6; % A
                app.keyMap('7') = 7; % B
                
                % 重置分数
                app.score = 0;
                if app.language=="CH"
                    app.Label_score.Text = ['分数： ', num2str(app.score)];
                else
                    app.Label_score.Text = ['Score: ', num2str(app.score)];
                end
                
                % 初始化音符
                app.currentNotes = struct('type', {}, 'x', {}, 'y', {});
                
                % 创建游戏计时器
                app.gameTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.08, ...
                    'TimerFcn', @(~,~) updateGameWrapper(app));
                start(app.gameTimer);
                
                % 设置键盘回调
                app.UIFigure.WindowKeyPressFcn = @app.keyPressed;              
                
                % 初始化音效播放器数组
                app.hitPlayers = [];
            end
        end

        % 辅助函数确保作用域正确
        function updateGameWrapper(app)
            if app.isGame==0
                return;
            end
            app.updateGame();
        end
        
        % 更新游戏状态
        function updateGame(app)
            cla(app.UIAxes_game);
            
            % 随机添加新音符 (简化版)
            if rand() < 0.1
                newNote = struct(...
                    'type', randi(7), ... % 1-7对应C-B
                    'x', randi([1,9]), ...
                    'y', 10);
                app.currentNotes = [app.currentNotes; newNote];
            end
            
            % 更新现有音符位置
            for i = 1:length(app.currentNotes)
                app.currentNotes(i).y = app.currentNotes(i).y - 0.2;
                
                % 绘制音符
                noteName = app.noteTypeToName(app.currentNotes(i).type);
                text(app.UIAxes_game, app.currentNotes(i).x, app.currentNotes(i).y, ...
                    noteName, ...
                    'FontSize', 20, ...
                    'Color', app.noteColor(app.currentNotes(i).type));
            end
            
            % 移除超出屏幕的音符
            app.currentNotes([app.currentNotes.y] < 0) = [];
            
            drawnow limitrate; % 立即更新
        end
        
        % 处理键盘敲击
        function keyPressed(app, ~, event)            
            if isKey(app.keyMap, event.Key)
                keyNum = app.keyMap(event.Key);
                
                % 检查是否击中音符 (简化版)
                hit = false;
                noteIdx = [];
                for i = 1:length(app.currentNotes)
                    if app.currentNotes(i).type == keyNum && ...
                       app.currentNotes(i).y < 2 && app.currentNotes(i).y > 0
                        hit = true;
                        noteIdx = i;
                        break;
                    end
                end
                
                if hit
                    % 播放击中音效
                    app.playHitSound(keyNum);
                    
                    % 更新分数和界面
                    app.score = app.score + 10;
                    if app.language=="CH"
                        app.Label_score.Text = ['分数： ', num2str(app.score)];
                    else
                        app.Label_score.Text = ['Score: ', num2str(app.score)];
                    end
                    
                    % 移除击中的音符
                    app.currentNotes(noteIdx) = [];
                end
            end
        end
        
        % 播放对应音符的音效
        function playHitSound(app, noteType)            
            if noteType >= 1 && noteType <= 7
                sound = app.noteSounds{noteType};
                player = audioplayer(sound, app.Fs); % 创建音乐播放器对象
                % 使用元胞数组存储播放器
                if isempty(app.hitPlayers)
                    app.hitPlayers = {player};
                else
                    app.hitPlayers{end+1} = player;
                end
                play(player); % 播放音频
                
                % 清理已完成播放的播放器
                app.cleanupPlayers();
            end
        end
        
        % 清理已完成播放的音效播放器
        function cleanupPlayers(app)
            % 清理无效播放器
            valid = cellfun(@isvalid, app.hitPlayers);
            app.hitPlayers = app.hitPlayers(valid);
        end

        % 辅助函数 - 频率转音符
        function notes = frequencyToNote(~,frequencies)
            % 简化版的频率到音符的转换
            noteNames = {'C', 'D', 'E', 'F', 'G', 'A', 'B'};
            noteFreqs = [261.63, 293.66, 329.63, 349.23, 392.00, 440.00, 493.88];
            notes = cell(size(frequencies));
            
            for i = 1:length(frequencies)
                if frequencies(i) == 0
                    notes{i} = '';
                    continue;
                end
                
                % 找到最接近的音符频率
                [~, idx] = min(abs(log2(frequencies(i)./noteFreqs)));
                notes{i} = noteNames{idx};
            end
        end
        
        % 辅助函数：音符类型转名称
        function name = noteTypeToName(~,type)
            noteNames = {'C', 'D', 'E', 'F', 'G', 'A', 'B'};
            if type >= 1 && type <= 7
                name = noteNames{type};
            else
                name = '';
            end
        end
        
        % 辅助函数：音符颜色
        function color = noteColor(~,type)
            switch type
                case 1 % C
                    color = [1 0 0]; % 红色
                case 2 % D
                    color = [0 1 0]; % 绿色
                case 3 % E
                    color = [0 0 1]; % 蓝色
                case 4 % F
                    color = [1 1 0]; % 黄色
                case 5 % G
                    color = [1 0 1]; % 紫色
                case 6 % A
                    color = [0 1 1]; % 青色
                case 7 % B
                    color = [1 0.5 0]; % 橙色
                otherwise
                    color = [0 0 0]; % 黑色
            end
        end

        % 初始化音乐播放器（音游）
        function initPlayer2(app)            
            app.SampleData=app.SampleDataInitial*app.volume; % 按音量调节采样数据
            
            % 播放数据非空时，实例化audioplayer对象
            if(~isempty(app.SampleData))
                app.playerMG=audioplayer(app.SampleData, app.Fs); % 实例化audioplayer对象
            else
                app.playerMG={}; % audioplayer对象赋空
            end
        end
        
        %% 预处理及边栏
        % 复制指定文件夹内所有文件（除去占位文件）至另一文件夹下
        function copyAll(~,folderPath,thirdPath)
            files = dir(fullfile(folderPath, '*'));
    
            % 遍历文件并复制（跳过 .gitkeep）
            for i = 1:length(files)
                [~, ~, ext] = fileparts(files(i).name);
                if ~strcmp(ext, '.gitkeep') && ~files(i).isdir
                    copyfile(fullfile(folderPath, files(i).name), thirdPath);
                end
            end
        end
        
        % 说明文档
        function menuDoc(app)
            delete(app.funDocFig);
            alertFig = uifigure('Name', 'Functions','Scrollable','on');
            parentPos = app.UIFigure.Position;
            initWidth = max(360, parentPos(3)*0.7);
            initHeight = max(500, parentPos(4)*0.7);
            alertFig.Position = [...
                parentPos(1)+(parentPos(3)-initWidth)/2, ...
                parentPos(2)+(parentPos(4)-initHeight)/2, ...
                initWidth, initHeight];
            app.funDocFig=alertFig;

            % 创建网格布局容器
            grid = uigridlayout(app.funDocFig);
            grid.RowHeight = {'1x'};  
            grid.ColumnWidth = {'1x'};  
            grid.Padding = [2 2 2 2];
            
            % 创建uihtml组件
            app.functionDoc = uihtml(grid);
            app.functionDoc.Layout.Row=1;
            app.functionDoc.Layout.Column=1;
            if app.language=="CH"
                app.functionDoc.HTMLSource=fullfile(app.appRoot, 'html','function.html');
            else
                app.functionDoc.HTMLSource=fullfile(app.appRoot, 'html','function_EN.html');
            end
        end

        % 参考链接
        function menuLink(app)
            delete(app.linkDocFig);
            alertFig = uifigure('Name', 'Functions','Scrollable','on');
            parentPos = app.UIFigure.Position;
            initWidth = max(360, parentPos(3)*0.7);
            initHeight = max(500, parentPos(4)*0.7);
            alertFig.Position = [...
                parentPos(1)+(parentPos(3)-initWidth)/2, ...
                parentPos(2)+(parentPos(4)-initHeight)/2, ...
                initWidth, initHeight];
            app.linkDocFig=alertFig;
        
            % 创建网格布局容器
            grid = uigridlayout(app.linkDocFig);
            grid.RowHeight = {'1x'};  
            grid.ColumnWidth = {'1x'};  
            grid.Padding = [2 2 2 2];
            
            % 创建uihtml组件
            app.linkDoc = uihtml(grid);
            app.linkDoc.Layout.Row=1;
            app.linkDoc.Layout.Column=1;
            if app.language=="CH"
                app.linkDoc.HTMLSource=fullfile(app.appRoot, 'html','link.html');
            else
                app.linkDoc.HTMLSource=fullfile(app.appRoot, 'html','link_EN.html');
            end
        end

        % 学校邮件
        function menuMail(app)
            if app.language=="CH"
                uialert(app.UIFigure, ['西北工业大学 软件学院 王子勋',newline,'学号：2024303578',newline,'wangzixun@mail.nwpu.edu.cn'], ...
                    '学校邮件', 'Icon', 'info');
            else
                uialert(app.UIFigure, ['Wang Zixun,School of Software,Northwestern Polytechnical University',newline,'Student ID:2024303578',newline,'wangzixun@mail.nwpu.edu.cn'], ...
                    'School E-mail', 'Icon', 'info');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            createTimer(app); % 创建计时器
            start(app.timerSet); % 启动计时器

            % 创建上下文菜单
            app.cmenu1=uicontextmenu(app.UIFigure);
            app.Menu_doc=uimenu(app.cmenu1, 'Text', '说明文档','Separator','on','MenuSelectedFcn', @(~,~) menuDoc(app));
            app.Menu_function.ContextMenu=app.cmenu1;

            app.cmenu2=uicontextmenu(app.UIFigure);
            app.Menu_link=uimenu(app.cmenu2, 'Text', '参考链接','Separator','on','MenuSelectedFcn', @(~,~) menuLink(app));
            app.Menu_reference.ContextMenu=app.cmenu2;

            app.cmenu3=uicontextmenu(app.UIFigure);
            app.Menu_mail=uimenu(app.cmenu3, 'Text', '学校邮件','Separator','on','MenuSelectedFcn', @(~,~) menuMail(app));
            app.Menu_contact.ContextMenu=app.cmenu3;

            % 创建html组件
            app.html=uihtml(app.GridLayout_theory);
            app.html.Layout.Row=1;
            app.html.Layout.Column=1; 

            app.html2=uihtml(app.GridLayout_html2);
            app.html2.Layout.Row=1;
            app.html2.Layout.Column=1; 
            
            app.html3=uihtml(app.GridLayout_html3);
            app.html3.Layout.Row=1;
            app.html3.Layout.Column=1;

            app.html4=uihtml(app.GridLayout_practice);
            app.html4.Layout.Row=1;
            app.html4.Layout.Column=1;

            app.instrumentName=fullfile(app.appRoot,"audios","Partita No. 3 in E Major for solo violin (violin).mp3");

            setupPortList(app); % 设置端口下拉框
            
            % 展开复选框
            expand(app.Tree_1);
            expand(app.Tree_2);
            expand(app.Tree_3);
            expand(app.Node_way1);
            expand(app.Node_way2);
            expand(app.Node_arduino);
            expand(app.Node_microphone);
            
            % 清空缓存数据
            dir=fullfile(app.appRoot,'data');
            deleteFilesOnly(dir);

            % 预处理文件复制
            copyfile(fullfile(app.appRoot,'pre','picFFT1','data.in'),fullfile(app.appRoot,'FourierDraw','data.in'));
            copyfile(fullfile(app.appRoot, 'pre', 'picFFT2', '*'), ...
                fullfile(app.appRoot, 'FourierCircleDrawing', 'FourierCircleDrawing1'));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 禁用自动路径清理
            setappdata(app.UIFigure, 'IgnoreCleanup', true);
            
            % 停止并删除计时器
            if isvalid(app.timerSet) % 检查计时器是否有效
                stop(app.timerSet);  % 停止计时器
                delete(app.timerSet); % 删除计时器
            end

            if ~isempty(app.gameTimer)
                stop(app.gameTimer);
                delete(app.gameTimer);
            end
            
            % 删除播放器
            if ~isempty(app.Player)
                stop(app.Player);
                delete(app.Player);
            end

            if ~isempty(app.player2)
                stop(app.player2);
                delete(app.player2);
            end

            if ~isempty(app.player3)
                stop(app.player3);
                delete(app.player3);
            end

            if ~isempty(app.playerMG)
                stop(app.playerMG);
                delete(app.playerMG);
            end
                      
            % 关闭 App
            delete(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab; % 当前选择栏目
            
            % 初始左边栏按钮背景色
            app.Button_theory.BackgroundColor=[0.96,0.96,0.96];
            app.Button_example.BackgroundColor=[0.96,0.96,0.96];
            app.Button_picture.BackgroundColor=[0.96,0.96,0.96];
            app.Button_audio.BackgroundColor=[0.96,0.96,0.96];
            app.Button_soundSignal.BackgroundColor=[0.96,0.96,0.96];
            app.Button_lightSignal.BackgroundColor=[0.96,0.96,0.96];
            app.Button_musicGame.BackgroundColor=[0.96,0.96,0.96];
            app.Button_practice.BackgroundColor=[0.96,0.96,0.96];
            app.Button_dataOutput.BackgroundColor=[0.96,0.96,0.96];
            
            % 切换栏目时停止其他栏目的音乐播放器对象播放，同时切换按钮颜色
            if selectedTab==app.Tab_theory % FFT理论
                app.Button_theory.BackgroundColor=[0.88,0.91,0.94]; % 选中按钮换色
                if ~isempty(app.Player)
                    stop(app.Player);
                end
                if ~isempty(app.player2)
                    stop(app.player2);
                end                
                if ~isempty(app.player3)
                    stop(app.player3);
                end
                if ~isempty(app.playerMG)
                    stop(app.playerMG);
                end
            elseif selectedTab==app.Tab_example % 简易图形示范
                app.Button_example.BackgroundColor=[0.88,0.91,0.94];
                if ~isempty(app.Player)
                    stop(app.Player);
                end
                if ~isempty(app.player2)
                    stop(app.player2);
                end                
                if ~isempty(app.player3)
                    stop(app.player3);
                end
                if ~isempty(app.playerMG)
                    stop(app.playerMG);
                end
            elseif selectedTab==app.Tab_picture % 图像FFT
                app.Button_picture.BackgroundColor=[0.88,0.91,0.94];
                if ~isempty(app.Player)
                    stop(app.Player);
                end
                if ~isempty(app.player2)
                    stop(app.player2);
                end                
                if ~isempty(app.player3)
                    stop(app.player3);
                end
                if ~isempty(app.playerMG)
                    stop(app.playerMG);
                end
            elseif selectedTab==app.Tab_audio % 音频FFT
                app.Button_audio.BackgroundColor=[0.88,0.91,0.94];         
                if ~isempty(app.player3)
                    stop(app.player3);
                end
                if ~isempty(app.playerMG)
                    stop(app.playerMG);
                end
            elseif selectedTab==app.Tab_soundSignal % arduino声信号处理
                app.Button_soundSignal.BackgroundColor=[0.88,0.91,0.94];
                if ~isempty(app.Player)
                    stop(app.Player);
                end
                if ~isempty(app.player2)
                    stop(app.player2);
                end   
                if ~isempty(app.playerMG)
                    stop(app.playerMG);
                end
            elseif selectedTab==app.Tab_lightSignal % arduino光信号处理
                app.Button_lightSignal.BackgroundColor=[0.88,0.91,0.94];
                if ~isempty(app.Player)
                    stop(app.Player);
                end
                if ~isempty(app.player2)
                    stop(app.player2);
                end                
                if ~isempty(app.player3)
                    stop(app.player3);
                end
                if ~isempty(app.playerMG)
                    stop(app.playerMG);
                end
            elseif selectedTab==app.Tab_musicGame % 实时音游
                app.Button_musicGame.BackgroundColor=[0.88,0.91,0.94];
                if ~isempty(app.Player)
                    stop(app.Player);
                end
                if ~isempty(app.player2)
                    stop(app.player2);
                end                
                if ~isempty(app.player3)
                    stop(app.player3);
                end
            elseif selectedTab==app.Tab_practice % 交互练习
                app.Button_practice.BackgroundColor=[0.88,0.91,0.94];
                if ~isempty(app.Player)
                    stop(app.Player);
                end
                if ~isempty(app.player2)
                    stop(app.player2);
                end                
                if ~isempty(app.player3)
                    stop(app.player3);
                end
                if ~isempty(app.playerMG)
                    stop(app.playerMG);
                end
            elseif selectedTab==app.Tab_dataOutput % 数据导出
                app.Button_dataOutput.BackgroundColor=[0.88,0.91,0.94];
                if ~isempty(app.Player)
                    stop(app.Player);
                end
                if ~isempty(app.player2)
                    stop(app.player2);
                end                
                if ~isempty(app.player3)
                    stop(app.player3);
                end
                if ~isempty(app.playerMG)
                    stop(app.playerMG);
                end
            end
        end

        % Button pushed function: Button_theory
        function Button_theoryPushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_theory; % 切换至选择的栏目
        end

        % Button pushed function: Button_example
        function Button_examplePushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_example; % 切换至选择的栏目
        end

        % Button pushed function: Button_picture
        function Button_picturePushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_picture; % 切换至选择的栏目
        end

        % Button pushed function: Button_audio
        function Button_audioPushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_audio; % 切换至选择的栏目
        end

        % Button pushed function: Button_soundSignal
        function Button_soundSignalPushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_soundSignal; % 切换至选择的栏目
        end

        % Button pushed function: Button_lightSignal
        function Button_lightSignalPushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_lightSignal; % 切换至选择的栏目
        end

        % Button pushed function: Button_musicGame
        function Button_musicGamePushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_musicGame; % 切换至选择的栏目
        end

        % Button pushed function: Button_practice
        function Button_practicePushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_practice; % 切换至选择的栏目
        end

        % Button pushed function: Button_dataOutput
        function Button_dataOutputPushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_dataOutput; % 切换至选择的栏目
        end

        % Image clicked function: ToggleTool_language
        function ToggleTool_languageImageClicked(app, event)
            % 切换语言选项
            if app.language=="CH"
                app.language="EN";
            else
                app.language="CH";
            end
        end

        % Button pushed function: Button_html4
        function Button_html4Pushed(app, event)
            % 打开网页
            web(fullfile(app.appRoot,'html','interactive', ...
                'An Interactive Introduction to Fourier Transforms.html'));
        end

        % Selection changed function: ButtonGroup_ways
        function ButtonGroup_waysSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_ways.SelectedObject; % 当前选择的按钮（方式1/方式2）
            if selectedButton==app.Button_way1 % 选择方式1（基于C++）
                % 隐藏组件
                app.Button_processing.Visible="off";
                app.EditField_edgeSampleRate.Visible="off";
                app.EditField_4Label.Visible="off";
                app.EditField_fourierThreshold.Visible="off";
                app.EditField_5Label.Visible="off";
                app.EditField_rotationSpeed.Visible="off";
                app.Label_4.Visible="off";
                app.DropDown_curveFitting.Visible="off";
                app.Label_5.Visible="off";
                app.DropDown_clearOrbit.Visible="off";
                app.Label_6.Visible="off";
                app.Image_svgPaths.Visible="off";
                app.Button_killPython.Visible="off";
                app.Button_openPde.Visible="off";
                app.Button_re1.Visible="off";
                app.Button_re2.Visible="off";
                
                points = readDataIn(app,'FourierDraw/data.in');  % 确保文件路径正确
                imgHeight = max(points(:,2)) + 10; % 根据实际情况调整
                points(:,2) = imgHeight - points(:,2);
                plotPathInApp(app, points); % 显示路径
            else % 选择方式2（基于Python）
                app.Button_processing.Visible="on";               
                app.Image_svgPaths.Visible="on";
                cla(app.UIAxes_edge); % 清除坐标区图像

                if app.picChoose==2 % 选择svg
                    app.EditField_edgeSampleRate.Visible='off';
                    app.EditField_4Label.Visible='off';
                    app.DropDown_curveFitting.Visible='off';
                    app.Label_6.Visible='off';
                    app.EditField_fourierThreshold.Visible="on";
                    app.EditField_5Label.Visible="on";
                    app.EditField_rotationSpeed.Visible="on";
                    app.Label_4.Visible="on";
                    app.Label_5.Visible="on";
                    app.DropDown_clearOrbit.Visible="on";
                    app.Button_killPython.Visible="on";
                    app.Button_openPde.Visible="on";
                    app.Button_re1.Visible="on";
                    app.Button_re2.Visible="on";
                elseif app.picChoose==1 % 选择jpg/png
                    app.EditField_edgeSampleRate.Visible='on';
                    app.EditField_4Label.Visible='on';
                    app.DropDown_curveFitting.Visible='on';
                    app.Label_6.Visible='on';
                    app.EditField_fourierThreshold.Visible="on";
                    app.EditField_5Label.Visible="on";
                    app.EditField_rotationSpeed.Visible="on";
                    app.Label_4.Visible="on";
                    app.Label_5.Visible="on";
                    app.DropDown_clearOrbit.Visible="on";
                    app.Button_killPython.Visible="on";
                    app.Button_openPde.Visible="on";
                    app.Button_re1.Visible="on";
                    app.Button_re2.Visible="on";
                else % 未选择
                    % 隐藏组件
                    app.EditField_edgeSampleRate.Visible="off";
                    app.EditField_4Label.Visible="off";
                    app.EditField_fourierThreshold.Visible="off";
                    app.EditField_5Label.Visible="off";
                    app.EditField_rotationSpeed.Visible="off";
                    app.Label_4.Visible="off";
                    app.DropDown_curveFitting.Visible="off";
                    app.Label_5.Visible="off";
                    app.DropDown_clearOrbit.Visible="off";
                    app.Label_6.Visible="off";
                    app.Button_killPython.Visible="off";
                    app.Button_openPde.Visible="off";
                    app.Button_re1.Visible="off";
                    app.Button_re2.Visible="off";
                end
            end
        end

        % Button pushed function: Button_importPic
        function Button_importPicPushed(app, event)
            if app.ButtonGroup_ways.SelectedObject==app.Button_way1 % 选择方式1（基于C++）
                % 导入图像文件jpg/png/bmp
                [fileName, pathName]=uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg,*.png,*.bmp)'}, '导入图像文件', 'defaultName');

                % 检查用户是否取消了选择
                if isequal(fileName, 0) || isequal(pathName, 0)
                    return;
                end

                app.picExit=1; % 图片存在
                fullPath=fullfile(pathName, fileName); % 图片路径
                
                imgData=imread(fullPath); % 读取图像数据矩阵              

                info=imfinfo(fullPath); % 读取图像信息

                subfolder = 'Images';
                savePath = fullfile(app.appRoot, 'FourierDraw', subfolder); % 保存路径
                
                % 创建子文件夹(如果不存在)
                if ~exist(savePath, 'dir')
                    mkdir(savePath);
                end

                % 生成文件名
                timestamp = datetime("now","Format","dd-MM-uuuu-HH-mm-ss");
                filename = ['image_' char(timestamp) '.bmp'];
                fullSavePath = fullfile(savePath, filename);
                app.copyPath=['Images/',filename];

                if lower(info.Format)=="bmp" % 如果导入的是bmp
                    copyfile(fullPath, fullSavePath);
                    app.Image_originalPic.ImageSource=imgData;
                else % 导入的是jpg/png
                    app.Image_originalPic.ImageSource=fullPath;
                    imwrite(imgData,fullSavePath,"bmp");
                end
            else % 选择方式2（基于Python）
                createAlertWay2(app);                
            end
        end

        % Button pushed function: Button_dynamicFFT
        function Button_dynamicFFTPushed(app, event)
            if app.ButtonGroup_ways.SelectedObject==app.Button_way1 % picFFT way1（基于C++）
                if app.picExit==0 % 当前图像文件存在，展示航小天的FFT图像
                    if app.language=="CH"
                        selection=uiconfirm(app.UIFigure, ['即将展示航小天的FFT图像（按住F5开始/结束）', ...
                            newline,'您确定要继续吗?'], '确认操作', ...
                        'Options', {'确定','取消'}, ...
                        'DefaultOption', 2, ...
                        'CancelOption', 2);
    
                        if selection=="确定"
                            exePath=fullfile(app.appRoot,"FourierDraw","fourier.exe");
                            winopen(exePath);
                        end
                    else
                        selection=uiconfirm(app.UIFigure, ['The FFT image of Hang Xiaotian will be displayed (press F5 to start/end)', ...
                            newline,'Are you sure you want to continue?'], 'Confirm the operation', ...
                        'Options', {'Confirm','Cancel'}, ...
                        'DefaultOption', 2, ...
                        'CancelOption', 2);
    
                        if selection=="Confirm"
                            exePath=fullfile(app.appRoot,"FourierDraw","fourier.exe");
                            winopen(exePath);
                        end
                    end
                else
                    % 依赖的C++运行程序路径
                    app.exePath1=fullfile(app.appRoot,"FourierDraw","kruskal.exe");
                    app.exePath2=fullfile(app.appRoot,"FourierDraw","fourier.exe");
          
                    createAlertWay1(app); % 创建picFFT的way1提示框
                    
                    % 数据保存
                    path=fullfile(app.appRoot,"data","picFFT","way1");
                    file=sprintf('picFFT_way1_%s.png',num2str(app.cntPicFFT));
                    app.cntPicFFT=app.cntPicFFT+1;
                    % 坐标区图像保存
                    exportgraphics(app.UIAxes_edge,fullfile(path,file),'Resolution',300,'ContentType','image'); % 保存为图片
                end
            else % picFFT way2（基于Python）                
                pre=fullfile(app.appRoot,'pre','processing','pro_path.txt'); % processing解压后文件夹的路径信息
                content = strtrim(fileread(pre)); % 提取processing程序信息

                if isempty(content) || ~isfolder(content) % processing文件夹路径为空或无效，弹出警告信息
                    if app.language=="CH"
                        uialert(app.UIFigure, 'Processing不存在！', 'information', 'Icon', 'warning');
                    else
                        uialert(app.UIFigure, "Processing doesn't exit!", 'information', 'Icon', 'warning');
                    end
                    return;
                end
                
                % 依赖的pde程序
                pdeFile1 = fullfile(app.appRoot, 'FourierCircleDrawing', 'FourierCircleDrawing1', ...
                    'FourierCircleDrawing1.pde'); % svg
                pdeFile2 = fullfile(app.appRoot, 'FourierCircleDrawing', 'FourierCircleDrawing2', ...
                    'FourierCircleDrawing2.pde'); % jpg/png
                
                if app.picExit==0 % 图像不存在，显示School of Software图像
                    if app.language=="CH"
                        selection=uiconfirm(app.UIFigure, ['即将展示School of Software图像（svg）', ...
                            newline,'您确定要继续吗?'], '确认操作', ...
                        'Options', {'确定','取消'}, ...
                        'DefaultOption', 2, ...
                        'CancelOption', 2);
    
                        if selection=="确定"
                            winopen(pdeFile1); % 打开pde文件
                        else
                            return;
                        end
                    else
                        selection=uiconfirm(app.UIFigure, ['School of Software using svg will be displayed', ...
                            newline,'Are you sure you want to continue?'], 'Confirm the operation', ...
                        'Options', {'Confirm','Cancel'}, ...
                        'DefaultOption', 2, ...
                        'CancelOption', 2);
    
                        if selection=="Confirm"
                            winopen(pdeFile1); % 打开pde文件
                        else
                            return;
                        end
                    end
                    % 显示School of Software图像
                    app.Image_originalPic.ImageSource=fullfile(app.appRoot,"pre","picFFT2","input.svg");
                    app.Image_svgPaths.ImageSource=fullfile(app.appRoot,"pre","picFFT2","input.svg");
                else % 图像存在
                    if app.pythonImported==0 % Python未导入，弹出警告信息
                        if app.language=="CH"
                            uialert(app.UIFigure, '请先导入Python解释器！', '警告', 'Icon', 'warning');
                        else
                            uialert(app.UIFigure, 'Please import Python interpreter first!', 'warning', 'Icon', 'warning');
                        end
                        return;
                    end

                    Button_killPythonPushed(app,event); % 打开终止Python对话框
                   
                    if app.picChoose==2 % 选择svg
                        app.Image_originalPic.ImageSource=fullfile(app.appRoot,"FourierCircleDrawing","FourierCircleDrawing1","input.svg");
                        app.Image_svgPaths.ImageSource=fullfile(app.appRoot,"FourierCircleDrawing","FourierCircleDrawing1","input.svg");  
                        
                        % 保存数据文件
                        Fourier_Threshold=app.EditField_fourierThreshold.Value;
                        Rotation_Speed=app.EditField_rotationSpeed.Value;
                        Clear_Orbit=app.DropDown_clearOrbit.Value;
                        dataTable=table(Fourier_Threshold,Rotation_Speed,Clear_Orbit, ...
                            'VariableNames',{'Fourier Threshold','Rotation Speed','Clear Orbit'});
                        path=fullfile(app.appRoot,'data','picFFT','way2','para');
                        file=sprintf('picFFT_way2_para_%s.csv',num2str(app.cntPicPa));
                        file2=sprintf('picFFT_way2_para_%s.mat',num2str(app.cntPicPa));
                        writetable(dataTable, fullfile(path,file));
                        save(fullfile(path,file2),'Fourier_Threshold','Rotation_Speed',"Clear_Orbit");
                        app.cntPicPa=app.cntPicPa+1;

                        processWay3(app,pdeFile1); % picFFT基于Python方式导入svg时的函数
                    elseif app.picChoose==1 % 选择jpg/png                        
                        app.Image_originalPic.ImageSource=app.picFile; % 显示原图

                        info=imfinfo(app.picFile); % 提取图像信息
                        width=info.Width; % 图像宽度
                        height=info.Height; % 图像高度
                        
                        % 保存数据文件
                        Sample_Rate=app.EditField_edgeSampleRate.Value;
                        Fourier_Threshold=app.EditField_fourierThreshold.Value;
                        Curve_Fitting=app.DropDown_curveFitting.Value;
                        Rotation_Speed=app.EditField_rotationSpeed.Value;
                        Clear_Orbit=app.DropDown_clearOrbit.Value;
                        dataTable=table(Sample_Rate,Fourier_Threshold,Curve_Fitting,Rotation_Speed,Clear_Orbit, ...
                            'VariableNames',{'Sample Rate','Fourier Threshold','Curve Fitting','Rotation Speed','Clear Orbit'});
                        path=fullfile(app.appRoot,'data','picFFT','way2','para');
                        file=sprintf('picFFT_way2_para_%s.csv',num2str(app.cntPicPa));
                        file2=sprintf('picFFT_way2_para_%s.mat',num2str(app.cntPicPa));
                        writetable(dataTable, fullfile(path,file));
                        save(fullfile(path,file2),'Sample_Rate','Fourier_Threshold','Curve_Fitting','Rotation_Speed',"Clear_Orbit");
                        app.cntPicPa=app.cntPicPa+1;

                        processWay2(app,width,height,pdeFile2); % picFFT基于Python方式导入jpg/png时的函数

                        file=fullfile(app.appRoot,"FourierCircleDrawing","linear script","picture.svg");
                        app.Image_svgPaths.ImageSource=file; % 显示路径图像
                        fileSave=sprintf('picFFT_way2_svg2_%s.svg',num2str(app.cntPicSvg2));
                        copyfile(file,fullfile(app.appRoot,'data','picFFT','way2','svg',fileSave)); % 保存图像
                    end
                end
            end
        end

        % Button pushed function: Button_instruction
        function Button_instructionPushed(app, event)
            delete(app.picDoc);  % 删除旧提示框
    
            if app.ButtonGroup_ways.SelectedObject==app.Button_way1 % 选择方式1（基于C++）的说明文档
                alertFig = uifigure('Name', 'way 1','Scrollable','on');
                parentPos = app.UIFigure.Position;
                initWidth = max(360, parentPos(3)*0.7);
                initHeight = max(500, parentPos(4)*0.7);
                alertFig.Position = [...
                    parentPos(1)+(parentPos(3)-initWidth)/2, ...
                    parentPos(2)+(parentPos(4)-initHeight)/2, ...
                    initWidth, initHeight];
                app.picDoc=alertFig;
            
                % 创建网格布局容器
                grid = uigridlayout(alertFig);
                grid.RowHeight = {'1x'};  
                grid.ColumnWidth = {'1x'};  
                grid.Padding = [2 2 2 2];
                
                % 创建uihtml组件
                app.picDocHtml1 = uihtml(grid);
                app.picDocHtml1.Layout.Row=1;
                app.picDocHtml1.Layout.Column=1;
                if app.language=="CH"
                    app.picDocHtml1.HTMLSource=fullfile(app.appRoot, 'html','picFFT_way1.html');
                else
                    app.picDocHtml1.HTMLSource=fullfile(app.appRoot, 'html','picFFT_way1_EN.html');
                end
            else % 选择方式2（基于Python）的说明文档
                alertFig = uifigure('Name', 'way 2','Scrollable','on');
                parentPos = app.UIFigure.Position;
                initWidth = max(360, parentPos(3)*0.7);
                initHeight = max(500, parentPos(4)*0.7);
                alertFig.Position = [...
                    parentPos(1)+(parentPos(3)-initWidth)/2, ...
                    parentPos(2)+(parentPos(4)-initHeight)/2, ...
                    initWidth, initHeight];
                app.picDoc=alertFig;
            
                % 创建网格布局容器
                grid = uigridlayout(alertFig);
                grid.RowHeight = {'1x'};  
                grid.ColumnWidth = {'1x'};  
                grid.Padding = [2 2 2 2];

                % 创建uihtml组件
                app.picDocHtml2 = uihtml(grid);
                app.picDocHtml2.Layout.Row=1;
                app.picDocHtml2.Layout.Column=1;
                if app.language=="CH"
                    app.picDocHtml2.HTMLSource=fullfile(app.appRoot, 'html','picFFT_way2.html');
                else
                    app.picDocHtml2.HTMLSource=fullfile(app.appRoot, 'html','picFFT_way2_EN.html');
                end
            end
        end

        % Image clicked function: ToggleTool_python
        function ToggleTool_pythonImageClicked(app, event)
            importPythonAlert(app); % 导入Python解释器路径信息提示框
            app.pythonImported=1; % 已导入
        end

        % Button pushed function: Button_processing
        function Button_processingPushed(app, event)
            pre=fullfile(app.appRoot,'pre','processing','pro_path.txt');
            content = strtrim(fileread(pre)); % 读取预处理文件夹的processing程序路径信息
            
            if isempty(content) || ~isfolder(content) % processing文件夹为空或无效
                if app.language=="CH"
                    targetDir = uigetdir('', '选择解压到的目标文件夹'); % 获取目标文件夹
                else
                    targetDir = uigetdir('', 'Select the destination folder to extract to'); % 获取目标文件夹
                end
                if isequal(targetDir, 0) % 用户取消选择
                    return;
                end
                
                unzip(fullfile(app.appRoot,"pre","processing-3.4-windows64.zip"),targetDir); % 解压processing安装包至目标文件夹
                % 解压完成提示
                if app.language=="CH"
                    uialert(app.UIFigure, sprintf('Processing程序已下载至%s。',targetDir), 'information', 'Icon', 'info');
                else
                    uialert(app.UIFigure, sprintf('Processing has been downloaded to %s.',targetDir), 'information', 'Icon', 'info');
                end
                
                % 存储processing保存路径，便于下一次运行App时使用
                fid = fopen(pre, 'w');
                fprintf(fid, '%s', targetDir);
                fclose(fid);
            else % processing程序（文件夹）已存在
                if app.language=="CH"
                    uialert(app.UIFigure, sprintf('Processing程序已存在于%s。',content), 'information', 'Icon', 'info');
                else
                    uialert(app.UIFigure, sprintf('Processing exits in %s.',content), 'information', 'Icon', 'info');
                end
            end
        end

        % Button pushed function: Button_importMusic
        function Button_importMusicPushed(app, event)
            % 1.读取音乐文件
            [fileNames, pathName]=uigetfile({'*.mp3;*.wav;*.flac', 'Audio Files (*.mp3, *.wav, *.flac)'}, '导入音乐文件', 'defaultName', 'MultiSelect','on');
            if(isfloat(fileNames))
                return;
            elseif(ischar(fileNames))
                fileNames={fileNames};
            end

            % 2.私有属性赋值，方便其他函数使用
            app.audioFileNames=fileNames;
            app.audioPathName=pathName;
            app.audioFileCount=length(fileNames);

            % 3.歌曲列表框显示设置
            app.ListBox_songs.Items=fileNames;
            app.ListBox_songs.Value=fileNames{1,1};
            app.ListBox_songs_2.Items=fileNames;
            app.ListBox_songs_2.Value=fileNames{1,1};

            % 4.从音乐文件中读取播放数据并初始化播放数据
            readMusicDataAndInit(app, fileNames{1,1});
        end

        % Value changed function: ListBox_songs
        function ListBox_songsValueChanged(app, event)
            value = app.ListBox_songs.Value; % 读取列表框的当前选中歌曲
            readMusicDataAndInit(app, value); % 音乐播放初始化
        end

        % Button pushed function: Button_playMusic
        function Button_playMusicPushed(app, event)
            if ~isempty(app.player2) % 乐器相关音乐播放器非空
                stop(app.player2); % 停止乐器播放
                cla(app.UIAxes_musicInstrument); % 清空乐器谐波结构坐标区
                return;
            end
            
            initPlayer(app); % 初始化音乐播放器对象
            if(~isempty(app.Player)) % 对象非空时
                play(app.Player, app.CurrentSample); % 播放
            else
                if app.language=="CH"
                    uialert(app.UIFigure, '请先导入歌曲', '警告');
                else
                    uialert(app.UIFigure, 'Please import music first', 'warning');
                end
            end
        end

        % Button pushed function: Button_pauseMusic
        function Button_pauseMusicPushed(app, event)
            if ~isempty(app.player2) % 乐器相关音乐播放器非空
                stop(app.player2); % 停止乐器播放
                cla(app.UIAxes_musicInstrument); % 清空乐器谐波结构坐标区
                return;
            end
            
            if(~isempty(app.Player)) % 对象非空时
                pause(app.Player); % 暂停
            else
                if app.language=="CH"
                    uialert(app.UIFigure, '请先导入歌曲', '警告');
                else
                    uialert(app.UIFigure, 'Please import music first', 'warning');
                end
            end
        end

        % Button pushed function: Button_resumeMusic
        function Button_resumeMusicPushed(app, event)
            if ~isempty(app.player2) % 乐器相关音乐播放器非空
                stop(app.player2); % 停止乐器播放
                cla(app.UIAxes_musicInstrument); % 清空乐器谐波结构坐标区
                return;
            end
            
            if(~isempty(app.Player)) % 对象非空时
                resume(app.Player); % 续播
            else
                if app.language=="CH"
                    uialert(app.UIFigure, '请先导入歌曲', '警告');
                else
                    uialert(app.UIFigure, 'Please import music first', 'warning');
                end
            end
        end

        % Button pushed function: Button_stopMusic
        function Button_stopMusicPushed(app, event)
            if ~isempty(app.player2) % 乐器相关音乐播放器非空
                % 保存数据
                path=fullfile(app.appRoot,"data","audioFFT","instrument");
                file=sprintf('audioFFT_instrument_%s.png',num2str(app.cntAudioIns));
                app.cntAudioIns=app.cntAudioIns+1;
                % 保存坐标区图像
                exportgraphics(app.UIAxes_musicInstrument,fullfile(path,file),'Resolution',300,'ContentType','image'); % 保存为图片

                stop(app.player2); % 停止乐器播放
                cla(app.UIAxes_musicInstrument); % 清空乐器谐波结构坐标区
                return;
            end
            
            if(~isempty(app.Player)) % 对象非空时
                stop(app.Player); % 停止
                app.Slider_progress.Value=0;
                app.Label_time.Text="00:00:00 / 00:00:00";
                
                % 保存数据
                if app.ButtonGroup_figure.SelectedObject==app.Button_whole % 当前展示全局图像
                    path=fullfile(app.appRoot,"data","audioFFT","waveform");
                    path2=fullfile(app.appRoot,"data","audioFFT","spectrum");
                    file=sprintf('audioFFT_waveform_total_%s.png',num2str(app.cntAudioWave));  
                    file2=sprintf('audioFFT_spectrum_total_%s.png',num2str(app.cntAudioWave)); 
                    exportgraphics(app.UIAxes_timeWave,fullfile(path,file),'Resolution',300,'ContentType','image'); % 保存为图片
                    exportgraphics(app.UIAxes_spectrum,fullfile(path2,file2),'Resolution',300,'ContentType','image'); % 保存为图片
                else % 当前展示局域图像
                    path=fullfile(app.appRoot,"data","audioFFT","waveform");
                    path2=fullfile(app.appRoot,"data","audioFFT","spectrum");
                    file=sprintf('audioFFT_waveform_partial_%s.png',num2str(app.cntAudioWave));  
                    file2=sprintf('audioFFT_spectrum_partial_%s.png',num2str(app.cntAudioWave)); 
                    exportgraphics(app.UIAxes_timeWave,fullfile(path,file),'Resolution',300,'ContentType','image'); % 保存为图片
                    exportgraphics(app.UIAxes_spectrum,fullfile(path2,file2),'Resolution',300,'ContentType','image'); % 保存为图片
                end

                cla(app.UIAxes_timeWave); % 清空波形坐标区
                cla(app.UIAxes_spectrum); % 清空频谱坐标区
                app.cntAudioWave=app.cntAudioWave+1;               
            else
                if app.language=="CH"
                    uialert(app.UIFigure, '请先导入歌曲', '警告');
                else
                    uialert(app.UIFigure, 'Please import music first', 'warning');
                end
            end  
        end

        % Button pushed function: Button_preMusic
        function Button_preMusicPushed(app, event)
            if ~isempty(app.player2) % 乐器相关音乐播放器非空
                stop(app.player2); % 停止乐器播放
                cla(app.UIAxes_musicInstrument); % 清空乐器谐波结构坐标区
                return;
            end
            
            % 1.获取当前歌曲的索引，即是第几首歌曲
            currentValue=app.ListBox_songs.Value; % 获取当前选择的歌曲名
            currentIndex=0; % 初始化当前歌曲索引
            for ii=1:app.audioFileCount % 循环对比
                if strcmp(currentValue, app.audioFileNames{1,ii})==1
                    currentIndex=ii;
                    break;
                end
            end

            % 2.根据当前歌曲索引，获取上一首歌曲名
            if (currentIndex==1) % 当前歌曲为第一首
                newValue=app.audioFileNames{1, app.FileCount}; 
            else % 当前歌曲不是第一首
                newValue=app.audioFileNames{1, currentIndex-1};
            end

            % 3.修改音乐列表框中的显示
            app.ListBox_songs.Value=newValue;

            % 4.读取上一首音乐数据，并初始化播放数据
            readMusicDataAndInit(app, newValue);

            % 5.调用按钮回调函数，立即播放
            Button_playMusicPushed(app, event);  
        end

        % Button pushed function: Button_nextMusic
        function Button_nextMusicPushed(app, event)
            if ~isempty(app.player2) % 乐器相关音乐播放器非空
                stop(app.player2); % 停止乐器播放
                cla(app.UIAxes_musicInstrument); % 清空乐器谐波结构坐标区
                return;
            end
            
            % 1.获取当前歌曲的索引，即是第几首歌曲
            currentValue=app.ListBox_songs.Value; % 获取当前选择的歌曲名
            currentIndex=0; % 初始化当前歌曲索引
            for ii=1:app.audioFileCount % 循环对比
                if strcmp(currentValue, app.audioFileNames{1,ii})==1
                    currentIndex=ii;
                    break;
                end
            end

            % 2.根据当前歌曲索引，获取下一首歌曲名
            if (currentIndex==app.audioFileCount) % 当前歌曲为最后一首
                newValue=app.audioFileNames{1, 1}; 
            else % 当前歌曲不是最后一首
                newValue=app.audioFileNames{1, currentIndex+1};
            end

            % 3.修改音乐列表框中的显示
            app.ListBox_songs.Value=newValue;

            % 4.读取下一首音乐数据，并初始化播放数据
            readMusicDataAndInit(app, newValue);

            % 5.调用按钮回调函数，立即播放
            Button_playMusicPushed(app, event);
        end

        % Value changed function: Slider_progress
        function Slider_progressValueChanged(app, event)
            value = app.Slider_progress.Value; % 获取进度条当前进度
            if ~isempty(app.Player) % 当前播放器非空
                newSamplePos=floor((app.Player.TotalSamples+1)*value); % 获得新的起始播放点数
                pause(app.Player); % 暂停当前播放
                play(app.Player,newSamplePos); % 从新的指定位置处播放
            else % 当前播放器为空
                app.Slider_progress.Value=0; % 进度条置0
            end
        end

        % Button pushed function: Button_stengthenVoice
        function Button_stengthenVoicePushed(app, event)
            app.isEnhanceHumanVoice = ~app.isEnhanceHumanVoice; % 改变当前是否人声增强标记

            if(~isempty(app.Player)) % 播放器非空
                app.CurrentSample=app.Player.CurrentSample; % 将当前播放位置进行存储
                Button_playMusicPushed(app, event); % 调用回调函数，立即播放
            end
        end

        % Button pushed function: Button_voicePrinciple
        function Button_voicePrinciplePushed(app, event)
            alertFig = uifigure('Name', 'Voice Enhancement','Scrollable','on');
            parentPos = app.UIFigure.Position;
            initWidth = max(360, parentPos(3)*0.7);
            initHeight = max(500, parentPos(4)*0.7);
            alertFig.Position = [...
                parentPos(1)+(parentPos(3)-initWidth)/2, ...
                parentPos(2)+(parentPos(4)-initHeight)/2, ...
                initWidth, initHeight];
            app.audioFFT=alertFig;
        
            % 创建网格布局容器
            grid = uigridlayout(alertFig);
            grid.RowHeight = {'1x'};  
            grid.ColumnWidth = {'1x'};  
            grid.Padding = [2 2 2 2];
            
            % 创建uihtml组件
            app.audioDocHtml = uihtml(grid);
            app.audioDocHtml.Layout.Row=1;
            app.audioDocHtml.Layout.Column=1;
            if app.language=="CH"
                app.audioDocHtml.HTMLSource=fullfile(app.appRoot, 'html','vocalEnhance.html');
            else
                app.audioDocHtml.HTMLSource=fullfile(app.appRoot, 'html','vocalEnhance_EN.html');
            end
        end

        % Selection changed function: ButtonGroup_figure
        function ButtonGroup_figureSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_figure.SelectedObject; % 获取当前选项
            if selectedButton==app.Button_part % 绘制局域图像
                app.figureType=1;
            else % 绘制全局图像
                app.figureType=0;
            end

            if(~isempty(app.Player)) % 播放器非空
                app.CurrentSample=app.Player.CurrentSample; % 将当前播放位置进行存储
                Button_playMusicPushed(app, event); % 调用回调函数，立即播放
            end
        end

        % Selection changed function: ButtonGroup_instrument
        function ButtonGroup_instrumentSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_instrument.SelectedObject; % 获取当前乐器选项
            if selectedButton==app.Button_violin
                app.instrumentName=fullfile(app.appRoot,"audios","Partita No. 3 in E Major for solo violin (violin).mp3");
            elseif selectedButton==app.Button_viola
                app.instrumentName=fullfile(app.appRoot,"audios","Arpeggione Sonata in A minor (viola).mp3");
            elseif selectedButton==app.Button_cello
                app.instrumentName=fullfile(app.appRoot,"audios","The Swan (cello).mp3");
            elseif selectedButton==app.Button_bass
                app.instrumentName=fullfile(app.appRoot,"audios","Autumn Leaves (bass).mp3");
            elseif selectedButton==app.Button_guitar
                app.instrumentName=fullfile(app.appRoot,"audios","Recuerdos de la Alhamba (guitar).mp3");
            elseif selectedButton==app.Button_keyboard
                app.instrumentName=fullfile(app.appRoot,"audios","Angle (keyboard).mp3");
            elseif selectedButton==app.Button_flute
                app.instrumentName=fullfile(app.appRoot,"audios","Flute Concerto No. 2 in D Major (flute).mp3");
            elseif selectedButton==app.Button_saxophone
                app.instrumentName=fullfile(app.appRoot,"audios","Take Five (saxophone).mp3");
            elseif selectedButton==app.Button_piano
                app.instrumentName=fullfile(app.appRoot,"audios","Moonlight Sonata (piano).mp3");
            elseif selectedButton==app.Button_drums
                app.instrumentName=fullfile(app.appRoot,"audios","Funk (drums).mp3");
            elseif selectedButton==app.Button_marimba
                app.instrumentName=fullfile(app.appRoot,"audios","Libertango (marimba).mp3");
            elseif selectedButton==app.Button_glockenspiel
                app.instrumentName=fullfile(app.appRoot,"audios","Hedwig's Theme (glockenspiel).mp3");
            elseif selectedButton==app.Button_harpsichord
                app.instrumentName=fullfile(app.appRoot,"audios","Chaconne BWV 1004 (harpsichord).mp3");
            elseif selectedButton==app.Button_bowbells
                app.instrumentName=fullfile(app.appRoot,"audios","Bow Bells (2014) for solo orchestra chimes and soundscape (bow bells).mp3");
            elseif selectedButton==app.Button_melodyorgan
                app.instrumentName=fullfile(app.appRoot,"audios","Sis Puella Magica (melody organ).mp3");
            elseif selectedButton==app.Button_trumpet
                app.instrumentName=fullfile(app.appRoot,"audios","Gabriel's Oboe (trumpet).mp3");
            end
        end

        % Button pushed function: Button_instruPlay
        function Button_instruPlayPushed(app, event)
            if buttonClicked(app) % 双击判定通过
                if ~isempty(app.Player)
                    stop(app.Player); % 停止音乐播放器
                end

                analyze_harmonics(app.instrumentName,app); % 乐器谐波结构分析函数
            end
        end

        % Image clicked function: PushTool_pictureInput
        function PushTool_pictureInputImageClicked(app, event)
            Button_importPicPushed(app, event); % 导入图片
        end

        % Image clicked function: PushTool_audioInput
        function PushTool_audioInputImageClicked(app, event)
            Button_importMusicPushed(app, event); % 导入音频
        end

        % Button pushed function: Button_bat
        function Button_batPushed(app, event)
            % 执行bat程序（防止端口占用冲突，需管理员权限）
            batPath = fullfile(app.appRoot,'pre','killctfxlauncher.bat');
            % 使用单引号包裹路径
            powerShellCmd = sprintf('Start-Process -FilePath ''%s'' -Verb RunAs', batPath);
            system(['powershell -Command "', powerShellCmd, '"']);
        end

        % Image clicked function: ToggleTool_arduino
        function ToggleTool_arduinoImageClicked(app, event)
            % 仅在arduino声信号与光信号界面进行回调
            if app.TabGroup.SelectedTab==app.Tab_soundSignal || app.TabGroup.SelectedTab==app.Tab_lightSignal
                alertFig = uifigure('Name', 'arduino program','Scrollable','on');
                parentPos = app.UIFigure.Position;
                initWidth = max(360, parentPos(3)*0.7);
                initHeight = max(500, parentPos(4)*0.7);
                alertFig.Position = [...
                    parentPos(1)+(parentPos(3)-initWidth)/2, ...
                    parentPos(2)+(parentPos(4)-initHeight)/2, ...
                    initWidth, initHeight];
                app.audioFFT=alertFig;
            
                % 创建网格布局容器
                grid = uigridlayout(alertFig);
                grid.RowHeight = {'1x', '0.2x'};  
                grid.ColumnWidth = {'1x'};  
                grid.Padding = [2 2 2 2];
                
                % 创建html组件
                arduinoHtml = uihtml(grid);
                arduinoHtml.Layout.Row=1;
                arduinoHtml.Layout.Column=1;
                
                % 创建按钮组件
                button=uibutton(grid);
                if app.language=="CH"
                    button.Text = '确保arduino IDE存在后可打开程序'; 
                    button.FontName = "楷体";
                else
                    button.Text = 'Make sure Arduino IDE exists and open the program'; 
                    button.FontName = "Segoe UI";
                end
                
                button.FontSize = 16;
                button.FontWeight = 'bold';
                button.HorizontalAlignment = 'center';
                button.WordWrap="on";
                button.Layout.Row = 2;
                button.Layout.Column = 1;         
                
                % 显示html组件内容，按钮触击回调打开ino文件（arduino程序）
                if app.TabGroup.SelectedTab==app.Tab_soundSignal % 声信号处理界面
                    arduinoHtml.HTMLSource=fullfile(app.appRoot,'html','arduinoSound.html');
                    root=fullfile(app.appRoot,"sound_sensor","sound_sensor.ino");
                    button.ButtonPushedFcn=@(src, event) system(sprintf('start "" "%s"', root));
                elseif app.TabGroup.SelectedTab==app.Tab_lightSignal % 光信号处理界面
                    arduinoHtml.HTMLSource=fullfile(app.appRoot,'html','arduinoLight.html');
                    root=fullfile(app.appRoot,"photoresistor","photoresistor.ino");
                    button.ButtonPushedFcn=@(src, event) system(sprintf('start "" "%s"', root));                   
                end
            end
        end

        % Clicked callback: DropDown_port
        function DropDown_portClicked(app, event)
            setupPortList(app); % 获取可用端口
        end

        % Button pushed function: Button_connectArduino
        function Button_connectArduinoPushed(app, event)
            try
                port = app.DropDown_port.Value; % 获取端口
                app.DropDown_port_2.Value=port;
                app.arduinoPort = serialport(port, 115200); % 连接端口，波特率设置115200
                configureTerminator(app.arduinoPort, "LF"); % 换行符
                flush(app.arduinoPort); % 清空arduino缓冲区

                app.Lamp.Color = 'green';
                app.Lamp_2.Color = 'green';
                % 连接端口成功提示
                if app.language=="CH"
                    msgbox('Arduino连接成功!', '成功');
                else
                    msgbox('Arduino connected!', 'success');
                end
            catch ME
                app.Lamp.Color = 'red';
                app.Lamp_2.Color = 'red';
                % 连接端口失败提示
                if app.language=="CH"
                    errordlg(['连接失败: ' ME.message], '错误');
                else
                    errordlg(['Connection failed: ' ME.message], 'failure');
                end
            end
        end

        % Button pushed function: Button_sStart
        function Button_sStartPushed(app, event)
            if app.ButtonGroup_device.SelectedObject==app.Button_devArduino % 当前使用arduino
                app.soundData = zeros(app.bufferSize, 1);
                app.featureHistory = table('Size',[0 5],...
                    'VariableNames',{'时间','均值','最大值','主频','过零率'},...
                    'VariableTypes',{'datetime','double','double','double','double'});
                app.soundSignalStart=1; % 记录开始采集声信号
                
                % 初始化数据缓存
                app.rawData = [];
                app.timeStamps = datetime.empty(0,1);
                app.silenceRegions = [];
                app.isRecording = true;
                app.soundFirst=0; % 第一个采集信号标记置0
                app.silenceFirst=0; % 第一个静音信号标记置0
            else % 当前使用PC麦克风
                % 检查是否已有录音对象
                if ~isempty(app.RecObj) && isrecording(app.RecObj)
                    return;
                end

                app.recAudioData=[]; % 录制数据清空

                % 创建 audiorecorder 对象
                app.RecObj = audiorecorder(app.recFS, app.Nbits, app.Nchannels);
                
                % 开始录制
                record(app.RecObj);

                app.Lamp.Color='yellow';
            end
        end

        % Button pushed function: Button_sEnd
        function Button_sEndPushed(app, event)
            if app.ButtonGroup_device.SelectedObject==app.Button_devArduino % 当前使用arduino
                app.soundSignalStart=0; % 记录结束采集声信号
                app.Lamp.Color=[0,1,1];
                app.isRecording = false; % 正在采集arduino声信号标记置假
                
                if isempty(app.rawData) % 缓冲区为空
                    return;
                end

                app.Slider_thresholdSound.Tooltip="(0,600]";
                
                % 转换为时间轴（秒）
                t = (0:length(app.rawData)-1)/app.fsSoundArduino;
                 
                % 标准化并保存为WAV
                app.rawData = uint8(app.rawData);                
                normalizedData = double(app.rawData) / double(intmax(class(app.rawData)));
                normalizedData = repelem(normalizedData, 8000 / app.fsSoundArduino);
                [file, path] = uiputfile('*.wav', '保存音频');
                if file % 用户完成选择
                    audiowrite(fullfile(path, file), normalizedData, 8000);
                    if app.language=="CH"
                        uialert(app.UIFigure, sprintf('已保存: %s\n时长: %.1fs',...
                            file, t), 'information', 'Icon', 'info');
                    else
                        uialert(app.UIFigure, sprintf('Saved: %s\n time length: %.1fs',...
                            file, t), 'information', 'Icon', 'info');
                    end
                    timestamp = datetime("now","Format","dd-MM-uuuu-HH-mm-ss");
                    filename = ['arduino_' char(timestamp) '.wav'];
                    app.audioOriginal=fullfile(app.appRoot,"saved_audios",filename);
                    copyfile(fullfile(path,file),app.audioOriginal);
                else
                    return;
                end
                
                % 保存数据
                path=fullfile(app.appRoot,'data','sound','arduino','para');
                file1=sprintf('sound_arduino_para_%s.csv',num2str(app.cntSound));
                file2=sprintf('sound_arduino_para_%s.mat',num2str(app.cntSound));
                writetable(app.dataTotalSound,fullfile(path, file1)); % 保存为csv
                data=app.dataTotalSound;
                save(fullfile(path, file2), 'data'); % 保存为mat
    
                path2=fullfile(app.appRoot,'data','sound','arduino','waveform');
                file3=sprintf('sound_arduino_waveform_%s.png',num2str(app.cntSound));
                path3=fullfile(app.appRoot,'data','sound','arduino','spectrum');
                file4=sprintf('sound_arduino_spectrum_%s.png',num2str(app.cntSound));
                path4=fullfile(app.appRoot,'data','sound','arduino','silence');
                file5=sprintf('sound_arduino_silence_%s.png',num2str(app.cntSound));
                % 保存坐标区图像
                exportgraphics(app.UIAxes_soundWaveform,fullfile(path2,file3),'Resolution',300,'ContentType','image'); % 保存为图片
                exportgraphics(app.UIAxes_soundSpectrum,fullfile(path3,file4),'Resolution',300,'ContentType','image'); % 保存为图片
                exportgraphics(app.UIAxes_soundSilence,fullfile(path4,file5),'Resolution',300,'ContentType','image'); % 保存为图片
                
                path5=fullfile(app.appRoot,'data','sound','arduino','threshold');
                file6=sprintf('sound_arduino_threshold_%s.mat',num2str(app.cntSound));
                threshold=app.Slider_thresholdSound.Value;
                save(fullfile(path5, file6), 'threshold'); % 保存为mat

                app.cntSound=app.cntSound+1;             
            else % 当前使用PC麦克风
                % 检查是否正在录制
                if isempty(app.RecObj) || ~isrecording(app.RecObj)
                    return;
                end
                
                % 停止录制
                stop(app.RecObj);

                app.Lamp.Color='green';
                
                % 提取音频数据
                app.recAudioData = getaudiodata(app.RecObj);

                % 计算时间轴
                t = (0:length(app.recAudioData)-1) / app.recFS;
                
                % 绘制波形
                plot(app.UIAxes_soundWaveform, t, app.recAudioData);
                app.UIAxes_soundWaveform.XGrid = 'on';
                app.UIAxes_soundWaveform.YGrid = 'on';

                N = length(app.recAudioData);
                f = (0:N-1) * (app.recFS / N);  % 频率轴
                Y = abs(fft(app.recAudioData));
                
                % 取单边频谱
                Y = Y(1:floor(N/2));
                f = f(1:floor(N/2));
                
                % 绘制频谱
                plot(app.UIAxes_soundSpectrum, f, Y);
                app.UIAxes_soundSpectrum.XGrid = 'on';
                app.UIAxes_soundSpectrum.YGrid = 'on';

                [file, path] = uiputfile('*.wav', '保存音频');
                if file % 用户完成选择
                    audiowrite(fullfile(path, file), app.recAudioData, app.recFS);
                    if app.language=="CH"
                        uialert(app.UIFigure, sprintf('已保存: %s\n',...
                        file), 'information', 'Icon', 'info');
                    else
                        uialert(app.UIFigure, sprintf('Saved: %s\n',...
                        file), 'information', 'Icon', 'info');
                    end
                    timestamp = datetime("now","Format","dd-MM-uuuu-HH-mm-ss");
                    filename = ['micro_' char(timestamp) '.wav'];
                    app.audioRec=fullfile(app.appRoot,"saved_audios",filename);
                    copyfile(fullfile(path,file),app.audioRec);
                else
                    return;
                end
                
                % 保存数据
                path2=fullfile(app.appRoot,'data','sound','micro','waveform');
                file3=sprintf('sound_micro_waveform_%s.png',num2str(app.cntSound2));
                path3=fullfile(app.appRoot,'data','sound','micro','spectrum');
                file4=sprintf('sound_micro_spectrum_%s.png',num2str(app.cntSound2));
                % 保存坐标区图像
                exportgraphics(app.UIAxes_soundWaveform,fullfile(path2,file3),'Resolution',300,'ContentType','image'); % 保存为图片
                exportgraphics(app.UIAxes_soundSpectrum,fullfile(path3,file4),'Resolution',300,'ContentType','image'); % 保存为图片
                app.cntSound2=app.cntSound2+1;                
            end
        end

        % Button pushed function: Button_sExport
        function Button_sExportPushed(app, event)
            if app.ButtonGroup_device.SelectedObject==app.Button_devArduino % 当前使用arduino
                if isempty(app.rawData) % 缓冲区为空
                    warndlg('无有效数据可导出！', '警告');
                    return;
                end
                
                % 转换为时间轴（秒）
                t = (0:length(app.rawData)-1)/app.fsSoundArduino;
                
                % 标记所有静音区间
                silenceMask = false(size(t));
                for i = 1:size(app.silenceRegions, 1)
                    silenceMask = silenceMask | ...
                        (t >= app.silenceRegions(i,1) & t <= app.silenceRegions(i,2));
                end
                
                % 提取有效音频段
                validData = app.rawData(~silenceMask);
                validTime = t(~silenceMask);
                
                % 标准化并保存为WAV
                validData = uint8(validData);                
                normalizedData = double(validData) / double(intmax(class(validData)));
                normalizedData = repelem(normalizedData, 8000 / app.fsSoundArduino);
                
                [file, path] = uiputfile('*.wav', '保存处理后的音频');
                if file % 用户已完成选择
                    audiowrite(fullfile(path, file), normalizedData, 8000);
                    if app.language=="CH"
                        uialert(app.UIFigure, sprintf('已保存: %s\n原时长: %.1fs | 处理后: %.1fs',...
                        file, max(t), max(validTime)), 'information', 'Icon', 'info');
                    else
                        uialert(app.UIFigure, sprintf('Saved: %s\n oringinal: %.1fs | processed: %.1fs',...
                        file, max(t), max(validTime)), 'information', 'Icon', 'info');
                    end
                    timestamp = datetime("now","Format","dd-MM-uuuu-HH-mm-ss");
                    filename = ['arduino_silence_' char(timestamp) '.wav'];
                    app.audioSilence=fullfile(app.appRoot,"saved_audios",filename);
                    copyfile(fullfile(path,file),app.audioSilence);
                else
                    return;
                end
            end
        end

        % Button pushed function: Button_sPlay
        function Button_sPlayPushed(app, event)
            if app.ButtonGroup_device.SelectedObject==app.Button_devArduino % 当前使用arduino
                if isempty(app.audioSilence) % 静音去除文件为空
                    return;
                end
                [y,fs]=audioread(app.audioSilence);                
            else % 当前使用PC麦克风
                if isempty(app.audioRec) % 麦克风读取文件为空
                    return;
                end
                [y,fs]=audioread(app.audioRec);
            end

            app.player3=audioplayer(y,fs); % 创建音乐播放器对象
            play(app.player3); % 播放音频
        end

        % Button pushed function: Button_sStop
        function Button_sStopPushed(app, event)
            if ~isempty(app.player3)
                stop(app.player3); % 停止音频
                app.player3={}; % 删除音乐播放器对象
            end
        end

        % Button pushed function: Button_changeVoicePlay
        function Button_changeVoicePlayPushed(app, event)
            if ~isempty(app.player3) && isvalid(app.player3)
                stop(app.player3); % 停止音频
                app.player3 = {}; % 播放器置空
            end
            
            % 对应设备的音频文件为空
            if app.ButtonGroup_device.SelectedObject==app.Button_devArduino && isempty(app.audioOriginal)
                return;
            elseif app.ButtonGroup_device.SelectedObject==app.Button_devMicrophone && isempty(app.audioRec)
                return;
            end

            if app.ButtonGroup_changeVoice.SelectedObject==app.Button_femaleVoice
                change_female(app); % 转女声
            elseif app.ButtonGroup_changeVoice.SelectedObject==app.Button_maleVoice
                change_male(app); % 转男声
            elseif app.ButtonGroup_changeVoice.SelectedObject==app.Button_cartoonVoice
                change_cartoon(app); % 转卡通
            else
                change_robot(app); % 转机器
            end
            
            [app.audio_modified,app.fsChange]=audioread(app.audioChangeFile); % 读取变声后的音频数据
            app.player3=audioplayer(app.audio_modified,app.fsChange); % 创建音乐播放器对象
            app.playerTemp=audioplayer(app.yChange,app.fsChange); % 创建临时播放器对象
            
            % 音频播放
            play(app.player3); 
            
            % 异步轻量级绘图
            decim_factor = 100;
            tAll = (0:decim_factor:length(app.audio_modified)-1)/app.fsChange;
            xAll = app.audio_modified(1:decim_factor:end,1);
            
            % 使用line对象绘制坐标区图像
            if isvalid(app.UIAxes_voiceChanged)
                cla(app.UIAxes_voiceChanged);
                line(app.UIAxes_voiceChanged, tAll, xAll, 'Color', 'k');
                app.UIAxes_voiceChanged.YLim = [-1.2, 1.2];
                drawnow limitrate; % 立即更新
            end
            
            % 原始音频坐标区图像绘制
            tAll2 = (0:decim_factor:length(app.yChange)-1)/app.fsChange;
            xAll2 = app.yChange(1:decim_factor:end,1);
            if isvalid(app.UIAxes_voiceOriginal)
                cla(app.UIAxes_voiceOriginal);
                line(app.UIAxes_voiceOriginal, tAll2, xAll2, 'Color', 'k');
                app.UIAxes_voiceOriginal.YLim = [-1.2, 1.2];
            end
        end

        % Button pushed function: Button_changeVoiceStop
        function Button_changeVoiceStopPushed(app, event)
            if app.ButtonGroup_device.SelectedObject==app.Button_devArduino % 当前使用arduino
                path2=fullfile(app.appRoot,'data','sound','arduino','original');
                file3=sprintf('sound_arduino_original_%s.png',num2str(app.cntVoice1));
                path3=fullfile(app.appRoot,'data','sound','arduino','processed');
                file4=sprintf('sound_arduino_processed_%s.png',num2str(app.cntVoice1));
                % 保存坐标区图像
                exportgraphics(app.UIAxes_voiceOriginal,fullfile(path2,file3),'Resolution',300,'ContentType','image'); % 保存为图片
                exportgraphics(app.UIAxes_voiceChanged,fullfile(path3,file4),'Resolution',300,'ContentType','image'); % 保存为图片

                app.cntVoice1=app.cntVoice1+1;
            else % 当前使用PC麦克风
                path2=fullfile(app.appRoot,'data','sound','micro','original');
                file3=sprintf('sound_micro_original_%s.png',num2str(app.cntVoice2));
                path3=fullfile(app.appRoot,'data','sound','micro','processed');
                file4=sprintf('sound_micro_processed_%s.png',num2str(app.cntVoice2));
                % 保存坐标区图像
                exportgraphics(app.UIAxes_voiceOriginal,fullfile(path2,file3),'Resolution',300,'ContentType','image'); % 保存为图片
                exportgraphics(app.UIAxes_voiceChanged,fullfile(path3,file4),'Resolution',300,'ContentType','image'); % 保存为图片

                app.cntVoice2=app.cntVoice2+1;
            end
            
            % 停止并释放所有播放器
            if ~isempty(app.player3) && isvalid(app.player3)
                stop(app.player3); % 停止播放器
                app.player3 = {};
            end
            
            if ~isempty(app.playerTemp) && isvalid(app.playerTemp)
                stop(app.playerTemp); % 停止播放器
                app.playerTemp = {};
            end
        end

        % Button pushed function: Button_circuit
        function Button_circuitPushed(app, event)
            alertFig = uifigure('Name', 'arduino circuit (Fritzing)','Scrollable','on');
            parentPos = app.UIFigure.Position;
            initWidth = max(360, parentPos(3)*0.7);
            initHeight = max(500, parentPos(4)*0.7);
            alertFig.Position = [...
                parentPos(1)+(parentPos(3)-initWidth)/2, ...
                parentPos(2)+(parentPos(4)-initHeight)/2, ...
                initWidth, initHeight];
            app.audioFFT=alertFig;
        
            % 创建网格布局容器
            grid = uigridlayout(alertFig);
            grid.RowHeight = {'1x'};  
            grid.ColumnWidth = {'1x'};  
            grid.Padding = [2 2 2 2];
            
            % 创建uiimage组件
            image=uiimage(grid);
            image.ImageSource=fullfile(app.appRoot,"pictures","Sound_sensor.jpg");
            image.Layout.Row=1;
            image.Layout.Column=1;
        end

        % Clicked callback: DropDown_port_2
        function DropDown_port_2Clicked(app, event)
            setupPortList(app); % 查找可用端口
        end

        % Button pushed function: Button_bat_2
        function Button_bat_2Pushed(app, event)
            Button_batPushed(app, event); % 执行bat
        end

        % Button pushed function: Button_circuit_2
        function Button_circuit_2Pushed(app, event)
            alertFig = uifigure('Name', 'arduino circuit (Fritzing)','Scrollable','on');
            parentPos = app.UIFigure.Position;
            initWidth = max(360, parentPos(3)*0.7);
            initHeight = max(500, parentPos(4)*0.7);
            alertFig.Position = [...
                parentPos(1)+(parentPos(3)-initWidth)/2, ...
                parentPos(2)+(parentPos(4)-initHeight)/2, ...
                initWidth, initHeight];
            app.audioFFT=alertFig;
        
            % 创建网格布局容器
            grid = uigridlayout(alertFig);
            grid.RowHeight = {'1x'};  
            grid.ColumnWidth = {'1x'};  
            grid.Padding = [2 2 2 2];
            
            % 创建uiimage组件
            image=uiimage(grid);
            image.ImageSource=fullfile(app.appRoot,"pictures","photoresistor.png");
            image.Layout.Row=1;
            image.Layout.Column=1;
        end

        % Button pushed function: Button_connectArduino_2
        function Button_connectArduino_2Pushed(app, event)
            try
                port = app.DropDown_port_2.Value; % 下拉框获取端口
                app.DropDown_port.Value=port;
                app.arduinoPort = serialport(port, 115200); % 连接端口，波特率设置115200
                configureTerminator(app.arduinoPort, "LF"); % 换行符
                flush(app.arduinoPort); % 清空arduino缓冲区

                app.Lamp.Color = 'green';
                app.Lamp_2.Color = 'green';
                if app.language=="CH"
                    msgbox('Arduino连接成功!', '成功');
                else
                    msgbox('Arduino connected!', 'success');
                end
            catch ME % 错误信息提示
                app.Lamp.Color = 'red';
                app.Lamp_2.Color = 'red';
                if app.language=="CH"
                    errordlg(['连接失败: ' ME.message], '错误');
                else
                    errordlg(['Connection failed: ' ME.message], 'failure');
                end
            end
        end

        % Button pushed function: Button_lstart
        function Button_lstartPushed(app, event)
            % 数据初始化
            app.lightData = zeros(app.bufferSize, 1);
            app.lightTimeStamps = zeros(app.bufferSize, 1);
            app.dataLog = table();
            app.sampleCount = 0;
            app.lightStartTime=datetime('now');

            app.lightSignalStart=1; % 标记光信号采样开始
            app.lightFirst=0; % 标记第一个光信号
            app.Lamp_2.Color='yellow';
            
            % 创建计时器对象
            app.dataTimer = timer(...
            'ExecutionMode', 'fixedRate', ...
            'Period', 0.5*1/app.fsSoundArduino, ...
            'TimerFcn', @(~,~) app.updateLight());
        
            % 启动计时器
            start(app.dataTimer);
        end

        % Button pushed function: Button_lend
        function Button_lendPushed(app, event)
            app.lightSignalStart=0;
            app.Lamp_2.Color='green';
            
            % 数据保存
            path=fullfile(app.appRoot,'data','light','para');
            file1=sprintf('light_para_%s.csv',num2str(app.cntLight));
            file2=sprintf('light_para_%s.mat',num2str(app.cntLight));
            writetable(app.dataTotalLight, fullfile(path, file1)); % 保存为csv
            data=app.dataTotalLight;
            save(fullfile(path, file2), 'data'); % 保存为mat

            path2=fullfile(app.appRoot,'data','light','waveform');
            file3=sprintf('light_waveform_%s.png',num2str(app.cntLight));
            path3=fullfile(app.appRoot,'data','light','spectrum');
            file4=sprintf('light_spectrum_%s.png',num2str(app.cntLight));
            exportgraphics(app.UIAxes_lightWaveform,fullfile(path2,file3),'Resolution',300,'ContentType','image'); % 保存为图片
            exportgraphics(app.UIAxes_lightSpectrum,fullfile(path3,file4),'Resolution',300,'ContentType','image'); % 保存为图片

            app.cntLight=app.cntLight+1;

            stop(app.dataTimer); % 停止计时器
            delete(app.dataTimer); % 删除计时器对象
        end

        % Value changed function: ListBox_songs_2
        function ListBox_songs_2ValueChanged(app, event)
            value = app.ListBox_songs_2.Value;
            readMusicDataAndInit(app, value);
            % 预加载音符音效
            app.loadNoteSounds();
        end

        % Button pushed function: Button_importMusic_2
        function Button_importMusic_2Pushed(app, event)
            % 1.读取音乐文件
            [fileNames, pathName]=uigetfile({'*.mp3;*.wav;*.flac', 'Audio Files (*.mp3, *.wav, *.flac)'}, '导入音乐文件', 'defaultName', 'MultiSelect','on');
            if(isfloat(fileNames))
                return;
            elseif(ischar(fileNames))
                fileNames={fileNames};
            end

            % 2.私有属性赋值，方便其他函数使用
            app.audioFileNames=fileNames;
            app.audioPathName=pathName;
            app.audioFileCount=length(fileNames);

            % 3.歌曲列表框显示设置
            app.ListBox_songs.Items=fileNames;
            app.ListBox_songs.Value=fileNames{1,1};
            app.ListBox_songs_2.Items=fileNames;
            app.ListBox_songs_2.Value=fileNames{1,1};

            % 4.从音乐文件中读取播放数据并初始化播放数据
            readMusicDataAndInit(app, fileNames{1,1});
            
            % 5.预加载音符音效
            app.loadNoteSounds();
        end

        % Value changed function: Slider_volume
        function Slider_volumeValueChanged(app, event)
            app.volume = app.Slider_volume.Value / 100; % 获取音量值
            if ~isempty(app.playerMG)
                app.CurrentSample=app.playerMG.CurrentSample; % 获取当前音频采样数据
                app.Button_playMGPushed(); % 在当前采样数据处继续播放
            end
        end

        % Button pushed function: Button_analyzeMusic
        function Button_analyzeMusicPushed(app, event)
            app.analyzeAudio(); % 使用FFT分析音频并提取音符

            % 存储数据
            path=fullfile(app.appRoot,'data','musicGame','wave');
            file=sprintf('musicGame_dominantFreqWave_%s.png',num2str(app.cntMG));
            exportgraphics(app.UIAxes_musicSpectrum,fullfile(path,file),'Resolution',300,'ContentType','image'); % 保存为图片
        end

        % Button pushed function: Button_startMG
        function Button_startMGPushed(app, event)
            app.isGame=1; % 正在游戏标志置1

            app.startGame(); % 开始游戏        
        end

        % Button pushed function: Button_stopMG
        function Button_stopMGPushed(app, event)
            app.isGame=0; % 正在游戏标志置0
            
            % 存储数据
            scores=app.score;
            path2=fullfile(app.appRoot,'data','musicGame','score');
            file2=sprintf('musicGame_score_%s.mat',num2str(app.cntMG));
            save(fullfile(path2,file2),"scores");

            cla(app.UIAxes_game); % 清空坐标区
            app.cntMG=app.cntMG+1;
            app.currentNotes = struct('type', {}, 'x', {}, 'y', {});

            if ~isempty(app.gameTimer)
                stop(app.gameTimer); % 停止计时器             
            end

            if ~isempty(app.playerMG)
                stop(app.playerMG); % 停止播放器对象
            end
        end

        % Button pushed function: Button_playMG
        function Button_playMGPushed(app, event)
            initPlayer2(app); % 初始化音乐播放器
            if(~isempty(app.playerMG)) % 对象非空时
                play(app.playerMG, app.CurrentSample); % 播放
            else
                if app.language=="CH"
                    uialert(app.UIFigure, '请先导入歌曲', '警告');
                else
                    uialert(app.UIFigure, 'Please import music first', 'warning');
                end
            end
        end

        % Button pushed function: Button_exportData
        function Button_exportDataPushed(app, event)
            % 选择主文件夹
            mainFolder = uigetdir('C:\', 'Select Main Folder');

            % 树中被勾选的节点列表
            check1=app.Tree_1.CheckedNodes;
            check2=app.Tree_2.CheckedNodes;
            check3=app.Tree_3.CheckedNodes;
            
            if mainFolder ~= 0 % 主文件夹已选择
                % 创建子文件夹（如果不存在）
                subFolder = 'FFT_Serial_Communication_Data';
                saveFolder = fullfile(mainFolder, subFolder);
                if ~isfolder(saveFolder)
                    mkdir(saveFolder);
                end
                thirdFolder = sprintf('Data_%s', datetime('now', 'Format','uuuu_MM_dd'));
                thirdPath = fullfile(saveFolder,thirdFolder);
                if ~isfolder(thirdPath)
                    mkdir(thirdPath);
                end

                % picFFT way1轮廓图像
                if ismember(app.Node_edge,check1)
                    folderPath = fullfile(app.appRoot,"data","picFFT","way1");
                    copyAll(app,folderPath,thirdPath);
                end

                % picFFT way2 svg图像
                if ismember(app.Node_svg,check1)
                    folderPath = fullfile(app.appRoot,"data","picFFT","way2","svg");
                    copyAll(app,folderPath,thirdPath);
                end

                % picFFT way2 参数表
                if ismember(app.Node_picPa,check1)
                    folderPath = fullfile(app.appRoot,"data","picFFT","way2","para");
                    copyAll(app,folderPath,thirdPath);
                end

                % picFFT way2 傅里叶系数
                if ismember(app.Node_picFFT,check1)
                    folderPath = fullfile(app.appRoot,"data","picFFT","way2","fft");
                    copyAll(app,folderPath,thirdPath);
                end

                % audioFFT 波形图
                if ismember(app.Node_audioWave,check1)
                    folderPath = fullfile(app.appRoot,"data","audioFFT","waveform");
                    copyAll(app,folderPath,thirdPath);
                end

                % audioFFT 频谱图
                if ismember(app.Node_audioSpectrum,check1)
                    folderPath = fullfile(app.appRoot,"data","audioFFT","spectrum");
                    copyAll(app,folderPath,thirdPath);
                end

                % audioFFT 乐器谐波
                if ismember(app.Node_instrument,check1)
                    folderPath = fullfile(app.appRoot,"data","audioFFT","instrument");
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino light 参数表
                if ismember(app.Node_ligntPa,check1)
                    folderPath = fullfile(app.appRoot,"data","light","para");
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino light 波形图
                if ismember(app.Node_lightWave,check1)
                    folderPath = fullfile(app.appRoot,"data","light","waveform");
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino light 频谱图
                if ismember(app.Node_lightSpectrum,check1)
                    folderPath = fullfile(app.appRoot,"data","light","spectrum");
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino sound 参数表
                if ismember(app.Node_arduinoPa,check2)
                    folderPath = fullfile(app.appRoot,"data","sound","arduino",'para');
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino sound 采集阈值
                if ismember(app.Node_threshold,check2)
                    folderPath = fullfile(app.appRoot,"data","sound","arduino",'threshold');
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino sound 波形图
                if ismember(app.Node_ardWave,check2)
                    folderPath = fullfile(app.appRoot,"data","sound","arduino",'waveform');
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino sound 频谱图
                if ismember(app.Node_ardSpectrum,check2)
                    folderPath = fullfile(app.appRoot,"data","sound","arduino",'spectrum');
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino sound 静音检测
                if ismember(app.Node_silence,check2)
                    folderPath = fullfile(app.appRoot,"data","sound","arduino",'silence');
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino sound 变声处理原波形图
                if ismember(app.Node_origin,check2)
                    folderPath = fullfile(app.appRoot,"data","sound","arduino",'original');
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino sound 变声处理加工波形图
                if ismember(app.Node_processed,check2)
                    folderPath = fullfile(app.appRoot,"data","sound","arduino",'processed');
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino sound 波形图（麦克风）
                if ismember(app.Node_micWave,check2)
                    folderPath = fullfile(app.appRoot,"data","sound","micro",'waveform');
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino sound 频谱图（麦克风）
                if ismember(app.Node_micSpectrum,check2)
                    folderPath = fullfile(app.appRoot,"data","sound","micro",'spectrum');
                    copyAll(app,folderPath,thirdPath);
                end
                
                % arduino sound 变声处理原波形图（麦克风）
                if ismember(app.Node_micOrigin,check2)
                    folderPath = fullfile(app.appRoot,"data","sound","micro",'original');
                    copyAll(app,folderPath,thirdPath);
                end

                % arduino sound 变声处理加工波形图（麦克风）
                if ismember(app.Node_micProcessed,check2)
                    folderPath = fullfile(app.appRoot,"data","sound","micro",'processed');
                    copyAll(app,folderPath,thirdPath);
                end

                % music game 波形
                if ismember(app.Node_mgSpectrum,check3)
                    folderPath = fullfile(app.appRoot,"data","musicGame","wave");
                    copyAll(app,folderPath,thirdPath);
                end

                % music game 分数
                if ismember(app.Node_score,check3)
                    folderPath = fullfile(app.appRoot,"data","musicGame","score");
                    copyAll(app,folderPath,thirdPath);
                end

                % 交互练习解析
                if ismember(app.Node_analysis,check3)
                    folderPath = fullfile(app.appRoot,"pre","dataOut","analysis.txt");
                    copyfile(folderPath,fullfile(thirdPath,'analysis.txt'));
                end

                if app.language=="CH"
                    uialert(app.UIFigure, sprintf('Data saved to:\n%s', thirdPath), 'Success','Icon','success');
                else
                    uialert(app.UIFigure, sprintf('数据保存至：\n%s', thirdPath), '成功','Icon','success');
                end
            end
        end

        % Button pushed function: Button_killPython
        function Button_killPythonPushed(app, event)
            alertFig = uifigure('Name', 'Interrupt Python','Scrollable','on');
            parentPos = app.UIFigure.Position;
            alertFig.Position = [...
                parentPos(1), ...
                parentPos(2), ...
                300, 200];
        
            % 创建网格布局容器
            grid = uigridlayout(alertFig);
            grid.RowHeight = {'1x','1x'};  
            grid.ColumnWidth = {'1x'};  
            grid.Padding = [2 2 2 2];
            
            % 创建标签组件
            label = uilabel(grid);
            if app.language=="CH"
                label.Text = '打开windows cmd命令行（快捷键：windows+R，输入cmd并回车），输入以下内容：'; 
                label.FontName = "楷体";
            else
                label.Text = 'Open the Windows cmd command line (shortcut key: Windows+R, enter cmd and press Enter), and enter the following:'; 
                label.FontName = "Segoe UI";
            end
            
            label.FontSize = 16;
            label.FontWeight = 'bold';
            label.HorizontalAlignment = 'center';
            label.WordWrap="on";
            label.Layout.Row = 1;
            label.Layout.Column = 1;
            
            % 创建文本区域组件
            textarea=uitextarea(grid);
            textarea.Editable=false;
            textarea.Value="taskkill /IM python.exe /F";
            textarea.Layout.Row = 2;
            textarea.Layout.Column = 1;
        end

        % Button pushed function: Button_openPde
        function Button_openPdePushed(app, event)
            pdeFile1 = fullfile(app.appRoot, 'FourierCircleDrawing', 'FourierCircleDrawing1', ...
                'FourierCircleDrawing1.pde'); % picFFT way2 svg

            pdeFile2 = fullfile(app.appRoot, 'FourierCircleDrawing', 'FourierCircleDrawing2', ...
                'FourierCircleDrawing2.pde'); % picFFT way2 jpg/png
            
            % 打开pde程序
            if app.picChoose==2
                winopen(pdeFile1);
            elseif app.picChoose==1
                winopen(pdeFile2);
            end
        end

        % Button pushed function: Button_re1
        function Button_re1Pushed(app, event)
            if app.picChoose==2 % 导入图像为svg
                inputFile = fullfile(app.appRoot,"FourierCircleDrawing","FourierCircleDrawing1","rotationSpeed.txt");
            elseif app.picChoose==1 % 导入图像为jpg/png
                inputFile = fullfile(app.appRoot,"FourierCircleDrawing","FourierCircleDrawing2","rotationSpeed.txt");
            end
            
            % 传递傅里叶圆旋转速度参数
            fid = fopen(inputFile, 'w');
            fprintf(fid, '%.4f', app.EditField_rotationSpeed.Value);
            fclose(fid);

            if app.language=="CH"
                uialert(app.UIFigure, '已重新传输参数', 'information', 'Icon', 'info');
            else
                uialert(app.UIFigure, "Parameter has been re-transmitted", 'information', 'Icon', 'info');
            end
        end

        % Button pushed function: Button_re2
        function Button_re2Pushed(app, event)
            if app.picChoose==2 % 导入图像为svg
                inputFile2 = fullfile(app.appRoot,"FourierCircleDrawing","FourierCircleDrawing1","clearOrbit.txt");
            elseif app.picChoose==1 % 导入图像为jpg/png
                inputFile2 = fullfile(app.appRoot,"FourierCircleDrawing","FourierCircleDrawing2","clearOrbit.txt");
            end
            
            % 传递是否清空轨道采参数
            fid2 = fopen(inputFile2, 'w');
            if app.DropDown_clearOrbit.Value==1
                fprintf(fid, '%d', 1); % 清除
            else
                fprintf(fid, '%d', 0); % 不清除
            end
            fclose(fid2);
        end

        % Value changed function: Slider_thresholdSound
        function Slider_thresholdSoundValueChanged(app, event)
            value = app.Slider_thresholdSound.Value;
            app.Slider_thresholdSound.Tooltip=num2str(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout_all = uigridlayout(app.UIFigure);
            app.Panel_up = uipanel(app.GridLayout_all);
            app.GridLayout_up = uigridlayout(app.Panel_up);
            app.Panel_menu = uipanel(app.GridLayout_up);
            app.GridLayout_menu = uigridlayout(app.Panel_menu);
            app.Menu_function = uibutton(app.GridLayout_menu, 'push');
            app.Menu_function.Text = '功能说明';
            app.Menu_reference = uibutton(app.GridLayout_menu, 'push');
            app.Menu_reference.Text = '参考资料';
            app.Menu_contact = uibutton(app.GridLayout_menu, 'push');
            app.Menu_contact.Text = '联系作者';
            app.Panel_tool = uipanel(app.GridLayout_up);
            app.GridLayout_tool = uigridlayout(app.Panel_tool);
            app.Panel_sep1 = uipanel(app.GridLayout_tool);
            app.Panel_sep2 = uipanel(app.GridLayout_tool);
            app.Panel_sep3 = uipanel(app.GridLayout_tool);
            app.Panel_sep4 = uipanel(app.GridLayout_tool);
            app.PushTool_pictureInput = uiimage(app.GridLayout_tool);
            app.PushTool_pictureInput.ImageClickedFcn = createCallbackFcn(app, @PushTool_pictureInputImageClicked, true);
            app.PushTool_pictureInput.ImageSource = fullfile(pathToMLAPP, 'pictures', 'picture_icon.png');
            app.PushTool_audioInput = uiimage(app.GridLayout_tool);
            app.PushTool_audioInput.ImageClickedFcn = createCallbackFcn(app, @PushTool_audioInputImageClicked, true);
            app.PushTool_audioInput.ImageSource = fullfile(pathToMLAPP, 'pictures', 'audio_icon.png');
            app.ToggleTool_arduino = uiimage(app.GridLayout_tool);
            app.ToggleTool_arduino.ImageClickedFcn = createCallbackFcn(app, @ToggleTool_arduinoImageClicked, true);
            app.ToggleTool_arduino.ImageSource = fullfile(pathToMLAPP, 'pictures', 'arduino_icon.png');
            app.ToggleTool_language = uiimage(app.GridLayout_tool);
            app.ToggleTool_language.ImageClickedFcn = createCallbackFcn(app, @ToggleTool_languageImageClicked, true);
            app.ToggleTool_language.ImageSource = fullfile(pathToMLAPP, 'pictures', 'chinese_icon.png');
            app.ToggleTool_python = uiimage(app.GridLayout_tool);
            app.ToggleTool_python.ImageClickedFcn = createCallbackFcn(app, @ToggleTool_pythonImageClicked, true);
            app.ToggleTool_python.ImageSource = fullfile(pathToMLAPP, 'pictures', 'python_icon.png');
            app.Label_warning = uilabel(app.GridLayout_up);
            app.Label_warning.Text = '请确保程序路径无中文字符';
            app.Panel_left = uipanel(app.GridLayout_all);
            app.GridLayout_left = uigridlayout(app.Panel_left);
            app.Image = uiimage(app.GridLayout_left);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pictures', 'school.png');
            app.Button_theory = uibutton(app.GridLayout_left, 'push');
            app.Button_theory.ButtonPushedFcn = createCallbackFcn(app, @Button_theoryPushed, true);
            app.Button_theory.Text = 'FFT理论';
            app.Button_example = uibutton(app.GridLayout_left, 'push');
            app.Button_example.ButtonPushedFcn = createCallbackFcn(app, @Button_examplePushed, true);
            app.Button_example.Text = '简易图形示范';
            app.Button_picture = uibutton(app.GridLayout_left, 'push');
            app.Button_picture.ButtonPushedFcn = createCallbackFcn(app, @Button_picturePushed, true);
            app.Button_picture.Text = '图像FFT';
            app.Button_audio = uibutton(app.GridLayout_left, 'push');
            app.Button_audio.ButtonPushedFcn = createCallbackFcn(app, @Button_audioPushed, true);
            app.Button_audio.Text = '音频FFT';
            app.Button_soundSignal = uibutton(app.GridLayout_left, 'push');
            app.Button_soundSignal.ButtonPushedFcn = createCallbackFcn(app, @Button_soundSignalPushed, true);
            app.Button_soundSignal.Text = '声信号处理';
            app.Button_lightSignal = uibutton(app.GridLayout_left, 'push');
            app.Button_lightSignal.ButtonPushedFcn = createCallbackFcn(app, @Button_lightSignalPushed, true);
            app.Button_lightSignal.Text = '光信号处理';
            app.Button_musicGame = uibutton(app.GridLayout_left, 'push');
            app.Button_musicGame.ButtonPushedFcn = createCallbackFcn(app, @Button_musicGamePushed, true);
            app.Button_musicGame.Text = '实时音游';
            app.Button_practice = uibutton(app.GridLayout_left, 'push');
            app.Button_practice.ButtonPushedFcn = createCallbackFcn(app, @Button_practicePushed, true);
            app.Button_practice.Text = '交互练习';
            app.Button_dataOutput = uibutton(app.GridLayout_left, 'push');
            app.Button_dataOutput.ButtonPushedFcn = createCallbackFcn(app, @Button_dataOutputPushed, true);
            app.Button_dataOutput.Text = '数据导出';
            app.Panel_right = uipanel(app.GridLayout_all);
            app.GridLayout_right = uigridlayout(app.Panel_right);
            app.Label_title = uilabel(app.GridLayout_right);
            app.Label_title.Text = '快速傅里叶变换串口通信交互学习入门';
            app.TabGroup = uitabgroup(app.GridLayout_right);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab_theory = uitab(app.TabGroup);
            app.Tab_theory.Title = 'FFT理论';
            app.GridLayout_theory = uigridlayout(app.Tab_theory);
            app.Tab_example = uitab(app.TabGroup);
            app.Tab_example.Title = '简易图形示范';
            app.GridLayout_example = uigridlayout(app.Tab_example);
            app.Label = uilabel(app.GridLayout_example);
            app.Label.Text = '';
            app.Slider_example = uislider(app.GridLayout_example);
            app.Slider_example.MajorTicks = [];
            app.Slider_example.MinorTicks = [];
            app.Panel_axes = uipanel(app.GridLayout_example);
            app.GridLayout_axes = uigridlayout(app.Panel_axes);
            app.UIAxes_phase = uiaxes(app.GridLayout_axes);
            title(app.UIAxes_phase, 'Title')
            xlabel(app.UIAxes_phase, 'X')
            ylabel(app.UIAxes_phase, 'Y')
            zlabel(app.UIAxes_phase, 'Z')
            app.UIAxes_time = uiaxes(app.GridLayout_axes);
            title(app.UIAxes_time, 'Title')
            xlabel(app.UIAxes_time, 'X')
            ylabel(app.UIAxes_time, 'Y')
            zlabel(app.UIAxes_time, 'Z')
            app.UIAxes_freq = uiaxes(app.GridLayout_axes);
            title(app.UIAxes_freq, 'Title')
            xlabel(app.UIAxes_freq, 'X')
            ylabel(app.UIAxes_freq, 'Y')
            zlabel(app.UIAxes_freq, 'Z')
            app.UIAxes_3d = uiaxes(app.GridLayout_axes);
            title(app.UIAxes_3d, 'Title')
            xlabel(app.UIAxes_3d, 'X')
            ylabel(app.UIAxes_3d, 'Y')
            zlabel(app.UIAxes_3d, 'Z')
            app.Panel_htmls = uipanel(app.GridLayout_example);
            app.GridLayout_htmls = uigridlayout(app.Panel_htmls);
            app.Panel_html2 = uipanel(app.GridLayout_htmls);
            app.GridLayout_html2 = uigridlayout(app.Panel_html2);
            app.Panel_html3 = uipanel(app.GridLayout_htmls);
            app.GridLayout_html3 = uigridlayout(app.Panel_html3);
            app.Panel_html4 = uipanel(app.GridLayout_example);
            app.GridLayout_html4 = uigridlayout(app.Panel_html4);
            app.Label_html4 = uilabel(app.GridLayout_html4);
            app.Label_html4.Text = '点击按钮，查看html网页';
            app.Button_html4 = uibutton(app.GridLayout_html4, 'push');
            app.Button_html4.ButtonPushedFcn = createCallbackFcn(app, @Button_html4Pushed, true);
            app.Button_html4.Text = 'Draw';
            app.Panel = uipanel(app.GridLayout_example);
            app.GridLayout_fourier = uigridlayout(app.Panel);
            app.Image_fourier = uiimage(app.GridLayout_fourier);
            app.Image_fourier.ImageSource = fullfile(pathToMLAPP, 'pictures', 'Fourier.png');
            app.Tab_picture = uitab(app.TabGroup);
            app.Tab_picture.Title = '图像FFT';
            app.GridLayout_picture = uigridlayout(app.Tab_picture);
            app.ButtonGroup_ways = uibuttongroup(app.GridLayout_picture);
            app.ButtonGroup_ways.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_waysSelectionChanged, true);
            app.Button_way1 = uiradiobutton(app.ButtonGroup_ways);
            app.Button_way1.Text = '方式1（基于C++）';
            app.Button_way1.Value = true;
            app.Button_way2 = uiradiobutton(app.ButtonGroup_ways);
            app.Button_way2.Text = '方式2（基于Python）';
            app.Panel_originalPic = uipanel(app.GridLayout_picture);
            app.GridLayout_originalPic = uigridlayout(app.Panel_originalPic);
            app.Image_originalPic = uiimage(app.GridLayout_originalPic);
            app.Image_originalPic.ImageSource = fullfile(pathToMLAPP, 'pictures', 'hangxiaotian.jpg');
            app.Panel_svgPaths = uipanel(app.GridLayout_picture);
            app.GridLayout_svgPaths = uigridlayout(app.Panel_svgPaths);
            app.UIAxes_edge = uiaxes(app.GridLayout_svgPaths);
            app.UIAxes_edge.XTick = [];
            app.UIAxes_edge.YTick = [];
            app.UIAxes_edge.ZTick = [];
            app.Image_svgPaths = uiimage(app.GridLayout_svgPaths);
            app.Image_svgPaths.ImageSource = fullfile(pathToMLAPP, 'pictures', 'hangxiaotian_svg.svg');
            app.Button_instruction = uibutton(app.GridLayout_picture, 'push');
            app.Button_instruction.ButtonPushedFcn = createCallbackFcn(app, @Button_instructionPushed, true);
            app.Button_instruction.Text = '说明（必看）';
            app.Button_importPic = uibutton(app.GridLayout_picture, 'push');
            app.Button_importPic.ButtonPushedFcn = createCallbackFcn(app, @Button_importPicPushed, true);
            app.Button_importPic.Text = '导入图片';
            app.TextArea_ways = uitextarea(app.GridLayout_picture);
            app.TextArea_ways.Value = {'方式1：'; '支持jpg/png格式；'; '支持bmp格式'; ''; '方式2：'; '支持jpg/png格式；'; '支持仅含1条路径的svg格式'};
            app.Label_originalPic = uilabel(app.GridLayout_picture);
            app.Label_originalPic.Text = '原图';
            app.Label_svgPaths = uilabel(app.GridLayout_picture);
            app.Label_svgPaths.Text = '轮廓/svg路径图像';
            app.Button_dynamicFFT = uibutton(app.GridLayout_picture, 'push');
            app.Button_dynamicFFT.ButtonPushedFcn = createCallbackFcn(app, @Button_dynamicFFTPushed, true);
            app.Button_dynamicFFT.Tooltip = {''};
            app.Button_dynamicFFT.Text = '图像FFT动态展示';
            app.Label_4 = uilabel(app.GridLayout_picture);
            app.Label_4.Text = '傅里叶圆旋转速度';
            app.EditField_rotationSpeed = uieditfield(app.GridLayout_picture, 'numeric');
            app.EditField_rotationSpeed.Limits = [0.0001 10];
            app.EditField_rotationSpeed.ValueDisplayFormat = '%.4f';
            app.EditField_rotationSpeed.Tooltip = {'范围[1e-4,10]'; '越大频率越高'};
            app.EditField_rotationSpeed.Value = 0.1;
            app.EditField_4Label = uilabel(app.GridLayout_picture);
            app.EditField_4Label.Text = '轮廓采样率';
            app.EditField_edgeSampleRate = uieditfield(app.GridLayout_picture, 'numeric');
            app.EditField_edgeSampleRate.Limits = [0 1];
            app.EditField_edgeSampleRate.Tooltip = {'范围[0,1]'; '越高计算时间越长'};
            app.EditField_edgeSampleRate.Value = 0.2;
            app.EditField_5Label = uilabel(app.GridLayout_picture);
            app.EditField_5Label.Text = '傅里叶系数计算阈值';
            app.EditField_fourierThreshold = uieditfield(app.GridLayout_picture, 'numeric');
            app.EditField_fourierThreshold.Limits = [10 2000];
            app.EditField_fourierThreshold.Tooltip = {'范围[10,2000]'; '越大计算时间越长'};
            app.EditField_fourierThreshold.Value = 500;
            app.Label_5 = uilabel(app.GridLayout_picture);
            app.Label_5.Text = '重绘是否清除已有轨道';
            app.DropDown_clearOrbit = uidropdown(app.GridLayout_picture);
            app.DropDown_clearOrbit.Items = {'是', '否'};
            app.DropDown_clearOrbit.ItemsData = [1 0];
            app.DropDown_clearOrbit.Value = 1;
            app.Label_6 = uilabel(app.GridLayout_picture);
            app.Label_6.Text = '是否进行曲线拟合';
            app.DropDown_curveFitting = uidropdown(app.GridLayout_picture);
            app.DropDown_curveFitting.Items = {'离散', '拟合'};
            app.DropDown_curveFitting.ItemsData = [0 1];
            app.DropDown_curveFitting.Tooltip = {'不建议进行拟合'};
            app.DropDown_curveFitting.Value = 0;
            app.Button_processing = uibutton(app.GridLayout_picture, 'push');
            app.Button_processing.ButtonPushedFcn = createCallbackFcn(app, @Button_processingPushed, true);
            app.Button_processing.Icon = fullfile(pathToMLAPP, 'pictures', 'UpdateIcon.png');
            app.Button_processing.Text = 'processing';
            app.Hyperlink_pythonDownload = uihyperlink(app.GridLayout_picture);
            app.Hyperlink_pythonDownload.URL = 'https://www.python.org/downloads/';
            app.Hyperlink_pythonDownload.Text = 'Python下载链接';
            app.GridLayout_fftOperate = uigridlayout(app.GridLayout_picture);
            app.Button_killPython = uibutton(app.GridLayout_fftOperate, 'push');
            app.Button_killPython.ButtonPushedFcn = createCallbackFcn(app, @Button_killPythonPushed, true);
            app.Button_killPython.Text = '中断Python';
            app.Button_openPde = uibutton(app.GridLayout_fftOperate, 'push');
            app.Button_openPde.ButtonPushedFcn = createCallbackFcn(app, @Button_openPdePushed, true);
            app.Button_openPde.Text = '打开pde文件';
            app.GridLayout_re1 = uigridlayout(app.GridLayout_picture);
            app.Button_re1 = uibutton(app.GridLayout_re1, 'push');
            app.Button_re1.ButtonPushedFcn = createCallbackFcn(app, @Button_re1Pushed, true);
            app.Button_re1.Text = '';
            app.GridLayout_re2 = uigridlayout(app.GridLayout_picture);
            app.Button_re2 = uibutton(app.GridLayout_re2, 'push');
            app.Button_re2.ButtonPushedFcn = createCallbackFcn(app, @Button_re2Pushed, true);
            app.Button_re2.Text = '';
            app.Tab_audio = uitab(app.TabGroup);
            app.Tab_audio.Title = '音频FFT';
            app.GridLayout_audio = uigridlayout(app.Tab_audio);
            app.ListBox_songs = uilistbox(app.GridLayout_audio);
            app.ListBox_songs.Items = {};
            app.ListBox_songs.ValueChangedFcn = createCallbackFcn(app, @ListBox_songsValueChanged, true);
            app.ListBox_songs.Value = {};
            app.Button_importMusic = uibutton(app.GridLayout_audio, 'push');
            app.Button_importMusic.ButtonPushedFcn = createCallbackFcn(app, @Button_importMusicPushed, true);
            app.Button_importMusic.Text = '导入歌曲';
            app.Button_playMusic = uibutton(app.GridLayout_audio, 'push');
            app.Button_playMusic.ButtonPushedFcn = createCallbackFcn(app, @Button_playMusicPushed, true);
            app.Button_playMusic.Text = '播放';
            app.Button_pauseMusic = uibutton(app.GridLayout_audio, 'push');
            app.Button_pauseMusic.ButtonPushedFcn = createCallbackFcn(app, @Button_pauseMusicPushed, true);
            app.Button_pauseMusic.Text = '暂停';
            app.Button_resumeMusic = uibutton(app.GridLayout_audio, 'push');
            app.Button_resumeMusic.ButtonPushedFcn = createCallbackFcn(app, @Button_resumeMusicPushed, true);
            app.Button_resumeMusic.Text = '续播';
            app.Button_stopMusic = uibutton(app.GridLayout_audio, 'push');
            app.Button_stopMusic.ButtonPushedFcn = createCallbackFcn(app, @Button_stopMusicPushed, true);
            app.Button_stopMusic.Text = '停止';
            app.Button_preMusic = uibutton(app.GridLayout_audio, 'push');
            app.Button_preMusic.ButtonPushedFcn = createCallbackFcn(app, @Button_preMusicPushed, true);
            app.Button_preMusic.Text = '上一首';
            app.Button_nextMusic = uibutton(app.GridLayout_audio, 'push');
            app.Button_nextMusic.ButtonPushedFcn = createCallbackFcn(app, @Button_nextMusicPushed, true);
            app.Button_nextMusic.Text = '下一首';
            app.Panel_timeWave = uipanel(app.GridLayout_audio);
            app.GridLayout_timeWave = uigridlayout(app.Panel_timeWave);
            app.UIAxes_timeWave = uiaxes(app.GridLayout_timeWave);
            xlabel(app.UIAxes_timeWave, 'Time (s)')
            ylabel(app.UIAxes_timeWave, 'Magnitude')
            app.UIAxes_timeWave.XTick = [];
            app.UIAxes_timeWave.YTick = [-1.2 -0.8 -0.4 0 0.4 0.8 1.2];
            app.UIAxes_timeWave.YTickLabel = {'-1.2'; '-0.8'; '-0.4'; '0'; '0.4'; '0.8'; '1.2'};
            app.UIAxes_timeWave.ZTick = [];
            app.Panel_spectrum = uipanel(app.GridLayout_audio);
            app.GridLayout_spectrum = uigridlayout(app.Panel_spectrum);
            app.UIAxes_spectrum = uiaxes(app.GridLayout_spectrum);
            xlabel(app.UIAxes_spectrum, 'Frequency (Hz)')
            ylabel(app.UIAxes_spectrum, 'Magnitude')
            app.UIAxes_spectrum.XTick = [0 1000 2000 3000 4000 5000];
            app.UIAxes_spectrum.XTickLabel = {'0'; '1000'; '2000'; '3000'; '4000'; '5000'};
            app.UIAxes_spectrum.YTick = [0 5 10 15 20];
            app.UIAxes_spectrum.YTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.UIAxes_spectrum.ZTick = [];
            app.Panel_musicInstrument = uipanel(app.GridLayout_audio);
            app.GridLayout_musicInstrument = uigridlayout(app.Panel_musicInstrument);
            app.UIAxes_musicInstrument = uiaxes(app.GridLayout_musicInstrument);
            app.UIAxes_musicInstrument.XTick = [];
            app.UIAxes_musicInstrument.YTick = [];
            app.UIAxes_musicInstrument.ZTick = [];
            app.Slider_progress = uislider(app.GridLayout_audio);
            app.Slider_progress.Limits = [0 1];
            app.Slider_progress.MajorTicks = [];
            app.Slider_progress.ValueChangedFcn = createCallbackFcn(app, @Slider_progressValueChanged, true);
            app.Slider_progress.MinorTicks = [];
            app.Panel_strengthenVoice = uipanel(app.GridLayout_audio);
            app.GridLayout_strengthenVoice = uigridlayout(app.Panel_strengthenVoice);
            app.Button_stengthenVoice = uibutton(app.GridLayout_strengthenVoice, 'push');
            app.Button_stengthenVoice.ButtonPushedFcn = createCallbackFcn(app, @Button_stengthenVoicePushed, true);
            app.Button_stengthenVoice.Tooltip = {''};
            app.Button_stengthenVoice.Text = '增强人声';
            app.Button_voicePrinciple = uibutton(app.GridLayout_strengthenVoice, 'push');
            app.Button_voicePrinciple.ButtonPushedFcn = createCallbackFcn(app, @Button_voicePrinciplePushed, true);
            app.Button_voicePrinciple.Text = '增强人声原理';
            app.Label_time = uilabel(app.GridLayout_strengthenVoice);
            app.Label_time.Text = '00:00:00 / 00:00:00';
            app.ButtonGroup_instrument = uibuttongroup(app.GridLayout_audio);
            app.ButtonGroup_instrument.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_instrumentSelectionChanged, true);
            app.ButtonGroup_instrument.Tooltip = {'连续点击两次乐器播放按钮开始播放；'; '单击停止按钮停止播放'};
            app.ButtonGroup_instrument.Title = '乐器谐波结构';
            app.Button_violin = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_violin.Text = '小提琴';
            app.Button_violin.Value = true;
            app.Button_viola = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_viola.Text = '中提琴';
            app.Button_cello = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_cello.Text = '大提琴';
            app.Button_bass = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_bass.Text = '贝斯';
            app.Button_guitar = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_guitar.Text = '吉他';
            app.Button_keyboard = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_keyboard.Text = '键盘';
            app.Button_flute = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_flute.Text = '长笛';
            app.Button_saxophone = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_saxophone.Text = '萨克斯';
            app.Button_piano = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_piano.Text = '钢琴';
            app.Button_drums = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_drums.Text = '架子鼓';
            app.Button_marimba = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_marimba.Text = '马林巴琴';
            app.Button_glockenspiel = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_glockenspiel.Text = '钟琴';
            app.Button_harpsichord = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_harpsichord.Text = '羽管键琴';
            app.Button_bowbells = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_bowbells.Text = '管钟';
            app.Button_melodyorgan = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_melodyorgan.Text = '口风琴';
            app.Button_trumpet = uiradiobutton(app.ButtonGroup_instrument);
            app.Button_trumpet.Text = '小号';
            app.ButtonGroup_figure = uibuttongroup(app.GridLayout_audio);
            app.ButtonGroup_figure.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_figureSelectionChanged, true);
            app.Button_part = uiradiobutton(app.ButtonGroup_figure);
            app.Button_part.Text = '局部';
            app.Button_part.Value = true;
            app.Button_whole = uiradiobutton(app.ButtonGroup_figure);
            app.Button_whole.Text = '整体';
            app.Button_instruPlay = uibutton(app.GridLayout_audio, 'push');
            app.Button_instruPlay.ButtonPushedFcn = createCallbackFcn(app, @Button_instruPlayPushed, true);
            app.Button_instruPlay.Text = '乐器播放';
            app.Tab_soundSignal = uitab(app.TabGroup);
            app.Tab_soundSignal.Title = 'arduino声信号处理';
            app.GridLayout_soundSignal = uigridlayout(app.Tab_soundSignal);
            app.UIAxes_soundSilence = uiaxes(app.GridLayout_soundSignal);
            title(app.UIAxes_soundSilence, 'Silence Test')
            xlabel(app.UIAxes_soundSilence, 'time (s)')
            ylabel(app.UIAxes_soundSilence, 'frequency (Hz)')
            zlabel(app.UIAxes_soundSilence, 'Z')
            app.UIAxes_soundSpectrum = uiaxes(app.GridLayout_soundSignal);
            title(app.UIAxes_soundSpectrum, 'Spectrum')
            zlabel(app.UIAxes_soundSpectrum, 'Z')
            app.UIAxes_soundWaveform = uiaxes(app.GridLayout_soundSignal);
            title(app.UIAxes_soundWaveform, 'Waveform')
            zlabel(app.UIAxes_soundWaveform, 'Z')
            app.Panel_arduino_bat = uipanel(app.GridLayout_soundSignal);
            app.GridLayout_arduino_bat = uigridlayout(app.Panel_arduino_bat);
            app.Button_bat = uibutton(app.GridLayout_arduino_bat, 'push');
            app.Button_bat.ButtonPushedFcn = createCallbackFcn(app, @Button_batPushed, true);
            app.Button_bat.Text = '执行bat';
            app.Button_circuit = uibutton(app.GridLayout_arduino_bat, 'push');
            app.Button_circuit.ButtonPushedFcn = createCallbackFcn(app, @Button_circuitPushed, true);
            app.Button_circuit.Text = '线路图';
            app.DropDown_port = uidropdown(app.GridLayout_arduino_bat);
            app.DropDown_port.Items = {};
            app.DropDown_port.ClickedFcn = createCallbackFcn(app, @DropDown_portClicked, true);
            app.DropDown_port.Value = {};
            app.Button_connectArduino = uibutton(app.GridLayout_arduino_bat, 'push');
            app.Button_connectArduino.ButtonPushedFcn = createCallbackFcn(app, @Button_connectArduinoPushed, true);
            app.Button_connectArduino.Text = '点击连接';
            app.UITable_extractData = uitable(app.GridLayout_soundSignal);
            app.UITable_extractData.ColumnName = {'时间'; '均值'; '最大值'; '主频'; '过零率'};
            app.UITable_extractData.RowName = {};
            app.Panel_soundProButtons = uipanel(app.GridLayout_soundSignal);
            app.GridLayout_soundProButtons = uigridlayout(app.Panel_soundProButtons);
            app.Button_sStart = uibutton(app.GridLayout_soundProButtons, 'push');
            app.Button_sStart.ButtonPushedFcn = createCallbackFcn(app, @Button_sStartPushed, true);
            app.Button_sStart.Text = '开始';
            app.Button_sEnd = uibutton(app.GridLayout_soundProButtons, 'push');
            app.Button_sEnd.ButtonPushedFcn = createCallbackFcn(app, @Button_sEndPushed, true);
            app.Button_sEnd.Text = '结束';
            app.Button_sExport = uibutton(app.GridLayout_soundProButtons, 'push');
            app.Button_sExport.ButtonPushedFcn = createCallbackFcn(app, @Button_sExportPushed, true);
            app.Button_sExport.Text = '导出静音过滤';
            app.Button_sPlay = uibutton(app.GridLayout_soundProButtons, 'push');
            app.Button_sPlay.ButtonPushedFcn = createCallbackFcn(app, @Button_sPlayPushed, true);
            app.Button_sPlay.Text = '播放';
            app.Button_sStop = uibutton(app.GridLayout_soundProButtons, 'push');
            app.Button_sStop.ButtonPushedFcn = createCallbackFcn(app, @Button_sStopPushed, true);
            app.Button_sStop.Text = '停止';
            app.Lamp = uilamp(app.GridLayout_soundProButtons);
            app.Panel_changeVoice = uipanel(app.GridLayout_soundSignal);
            app.Panel_changeVoice.Title = '变声处理';
            app.GridLayout_changeVoice = uigridlayout(app.Panel_changeVoice);
            app.UIAxes_voiceChanged = uiaxes(app.GridLayout_changeVoice);
            title(app.UIAxes_voiceChanged, 'Processed')
            app.UIAxes_voiceOriginal = uiaxes(app.GridLayout_changeVoice);
            title(app.UIAxes_voiceOriginal, 'Original')
            app.ButtonGroup_changeVoice = uibuttongroup(app.GridLayout_changeVoice);
            app.Button_femaleVoice = uiradiobutton(app.ButtonGroup_changeVoice);
            app.Button_femaleVoice.Text = '转女声';
            app.Button_femaleVoice.Value = true;
            app.Button_maleVoice = uiradiobutton(app.ButtonGroup_changeVoice);
            app.Button_maleVoice.Text = '转男声';
            app.Button_cartoonVoice = uiradiobutton(app.ButtonGroup_changeVoice);
            app.Button_cartoonVoice.Text = '转卡通';
            app.Button_robotVoice = uiradiobutton(app.ButtonGroup_changeVoice);
            app.Button_robotVoice.Text = '转机器';
            app.Button_changeVoiceStop = uibutton(app.GridLayout_changeVoice, 'push');
            app.Button_changeVoiceStop.ButtonPushedFcn = createCallbackFcn(app, @Button_changeVoiceStopPushed, true);
            app.Button_changeVoiceStop.Text = '停止';
            app.Button_changeVoicePlay = uibutton(app.GridLayout_changeVoice, 'push');
            app.Button_changeVoicePlay.ButtonPushedFcn = createCallbackFcn(app, @Button_changeVoicePlayPushed, true);
            app.Button_changeVoicePlay.Text = '播放';
            app.ButtonGroup_device = uibuttongroup(app.GridLayout_changeVoice);
            app.Button_devArduino = uiradiobutton(app.ButtonGroup_device);
            app.Button_devArduino.Text = '使用arduino';
            app.Button_devArduino.Value = true;
            app.Button_devMicrophone = uiradiobutton(app.ButtonGroup_device);
            app.Button_devMicrophone.Text = '使用麦克风';
            app.TextArea_soundSignal = uitextarea(app.GridLayout_soundSignal);
            app.TextArea_soundSignal.Value = {'本界面实现matlab与arduino交互的音频特征分析、静音检测及变声处理。请先输入arduino连接端口（请在PC设备管理器上确认），执行bat程序（并确保无其他程序占用端口），查看并连接线路图，点击右上角图标（内有arduino下载链接）复制arduino程序并上传至arduino uno开发板（波特率设置9600），确保关闭arduino程序后通过开始与结束按钮读取声音传感器传递的音频信息。'};
            app.Panel_thresholdSound = uipanel(app.GridLayout_soundSignal);
            app.GridLayout_thresholdSound = uigridlayout(app.Panel_thresholdSound);
            app.Label_7 = uilabel(app.GridLayout_thresholdSound);
            app.Label_7.Tooltip = {'可手动调节声音传感器的电容以缩放传递的信号'};
            app.Label_7.Text = '信号采集最低值';
            app.Slider_thresholdSound = uislider(app.GridLayout_thresholdSound);
            app.Slider_thresholdSound.Limits = [0 600];
            app.Slider_thresholdSound.MajorTicks = [];
            app.Slider_thresholdSound.ValueChangedFcn = createCallbackFcn(app, @Slider_thresholdSoundValueChanged, true);
            app.Slider_thresholdSound.Tooltip = {'(0,600]'};
            app.Slider_thresholdSound.Value = 500;
            app.Tab_lightSignal = uitab(app.TabGroup);
            app.Tab_lightSignal.Title = 'arduino光信号处理';
            app.GridLayout_lightSignal = uigridlayout(app.Tab_lightSignal);
            app.UIAxes_lightSpectrum = uiaxes(app.GridLayout_lightSignal);
            title(app.UIAxes_lightSpectrum, 'spectrum')
            app.UIAxes_lightWaveform = uiaxes(app.GridLayout_lightSignal);
            title(app.UIAxes_lightWaveform, 'waveform')
            app.UITable_light = uitable(app.GridLayout_lightSignal);
            app.UITable_light.ColumnName = {'时间'; '电阻'; '相对光强'; '峰值频率'; '频谱能量'};
            app.UITable_light.RowName = {};
            app.Panel_lightProButtons = uipanel(app.GridLayout_lightSignal);
            app.GridLayout_lightProButtons = uigridlayout(app.Panel_lightProButtons);
            app.Button_lstart = uibutton(app.GridLayout_lightProButtons, 'push');
            app.Button_lstart.ButtonPushedFcn = createCallbackFcn(app, @Button_lstartPushed, true);
            app.Button_lstart.Text = '开始';
            app.Button_lend = uibutton(app.GridLayout_lightProButtons, 'push');
            app.Button_lend.ButtonPushedFcn = createCallbackFcn(app, @Button_lendPushed, true);
            app.Button_lend.Text = '结束';
            app.Lamp_2 = uilamp(app.GridLayout_lightProButtons);
            app.TextArea_lightSignal = uitextarea(app.GridLayout_lightSignal);
            app.TextArea_lightSignal.Value = {'本界面实现matlab与arduino交互的光强信号时域波形及频谱能量分布分析。请先输入arduino连接端口（请在PC设备管理器上确认），执行bat程序（并确保无其他程序占用端口），查看并连接线路图，点击右上角图标（内有arduino下载链接）复制arduino程序并上传至arduino uno开发板（波特率设置115200），确保关闭arduino程序后点击连接按钮，通过开始与结束按钮读取光敏传感器传递的光敏电阻信息。'};
            app.Panel_arduino_bat_2 = uipanel(app.GridLayout_lightSignal);
            app.GridLayout_arduino_bat_2 = uigridlayout(app.Panel_arduino_bat_2);
            app.Button_bat_2 = uibutton(app.GridLayout_arduino_bat_2, 'push');
            app.Button_bat_2.ButtonPushedFcn = createCallbackFcn(app, @Button_bat_2Pushed, true);
            app.Button_bat_2.Text = '执行bat';
            app.Button_circuit_2 = uibutton(app.GridLayout_arduino_bat_2, 'push');
            app.Button_circuit_2.ButtonPushedFcn = createCallbackFcn(app, @Button_circuit_2Pushed, true);
            app.Button_circuit_2.Text = '线路图';
            app.DropDown_port_2 = uidropdown(app.GridLayout_arduino_bat_2);
            app.DropDown_port_2.Items = {};
            app.DropDown_port_2.ClickedFcn = createCallbackFcn(app, @DropDown_port_2Clicked, true);
            app.DropDown_port_2.Value = {};
            app.Button_connectArduino_2 = uibutton(app.GridLayout_arduino_bat_2, 'push');
            app.Button_connectArduino_2.ButtonPushedFcn = createCallbackFcn(app, @Button_connectArduino_2Pushed, true);
            app.Button_connectArduino_2.Text = '点击连接';
            app.Tab_musicGame = uitab(app.TabGroup);
            app.Tab_musicGame.Title = '实时音游';
            app.GridLayout_musicGame = uigridlayout(app.Tab_musicGame);
            app.UIAxes_musicSpectrum = uiaxes(app.GridLayout_musicGame);
            title(app.UIAxes_musicSpectrum, 'Dominant Frequencies Over Time')
            xlabel(app.UIAxes_musicSpectrum, 'Frame')
            ylabel(app.UIAxes_musicSpectrum, 'Frequency (Hz)')
            app.UIAxes_musicSpectrum.XTick = [];
            app.UIAxes_musicSpectrum.YTick = [];
            app.UIAxes_game = uiaxes(app.GridLayout_musicGame);
            xlabel(app.UIAxes_game, '------------------------------------------------------------------------------------------------------------------------')
            app.UIAxes_game.XTick = [];
            app.UIAxes_game.YTick = [];
            app.ListBox_songs_2 = uilistbox(app.GridLayout_musicGame);
            app.ListBox_songs_2.Items = {};
            app.ListBox_songs_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_songs_2ValueChanged, true);
            app.ListBox_songs_2.Value = {};
            app.TextArea_keyInstruction = uitextarea(app.GridLayout_musicGame);
            app.TextArea_keyInstruction.Value = {'keyboard:'; '1 - C'; '2 - D'; '3 - E'; '4 - F'; '5 - G'; '6 - A'; '7 - B'};
            app.Button_importMusic_2 = uibutton(app.GridLayout_musicGame, 'push');
            app.Button_importMusic_2.ButtonPushedFcn = createCallbackFcn(app, @Button_importMusic_2Pushed, true);
            app.Button_importMusic_2.Text = '导入歌曲';
            app.Button_analyzeMusic = uibutton(app.GridLayout_musicGame, 'push');
            app.Button_analyzeMusic.ButtonPushedFcn = createCallbackFcn(app, @Button_analyzeMusicPushed, true);
            app.Button_analyzeMusic.Text = 'FFT分析';
            app.Panel_buttonsMG = uipanel(app.GridLayout_musicGame);
            app.GridLayout_buttonsMG = uigridlayout(app.Panel_buttonsMG);
            app.Button_startMG = uibutton(app.GridLayout_buttonsMG, 'push');
            app.Button_startMG.ButtonPushedFcn = createCallbackFcn(app, @Button_startMGPushed, true);
            app.Button_startMG.Text = '开始';
            app.Button_stopMG = uibutton(app.GridLayout_buttonsMG, 'push');
            app.Button_stopMG.ButtonPushedFcn = createCallbackFcn(app, @Button_stopMGPushed, true);
            app.Button_stopMG.Text = '结束';
            app.Label_score = uilabel(app.GridLayout_buttonsMG);
            app.Label_score.Text = '分数：';
            app.Label_8 = uilabel(app.GridLayout_buttonsMG);
            app.Label_8.Text = '音量';
            app.Slider_volume = uislider(app.GridLayout_buttonsMG);
            app.Slider_volume.ValueChangedFcn = createCallbackFcn(app, @Slider_volumeValueChanged, true);
            app.Slider_volume.Value = 50;
            app.Button_playMG = uibutton(app.GridLayout_buttonsMG, 'push');
            app.Button_playMG.ButtonPushedFcn = createCallbackFcn(app, @Button_playMGPushed, true);
            app.Button_playMG.Text = '播放';
            app.Tab_practice = uitab(app.TabGroup);
            app.Tab_practice.Title = '交互练习';
            app.GridLayout_practice = uigridlayout(app.Tab_practice);
            app.Tab_dataOutput = uitab(app.TabGroup);
            app.Tab_dataOutput.Title = '数据导出';
            app.GridLayout_dataOutput = uigridlayout(app.Tab_dataOutput);
            app.Tree_1 = uitree(app.GridLayout_dataOutput, 'checkbox');
            app.Node_picFFT = uitreenode(app.Tree_1);
            app.Node_picFFT.Text = '图像FFT';
            app.Node_way1 = uitreenode(app.Node_picFFT);
            app.Node_way1.Text = '方式1';
            app.Node_edge = uitreenode(app.Node_way1);
            app.Node_edge.Text = '轮廓图像（导出png）';
            app.Node_way2 = uitreenode(app.Node_picFFT);
            app.Node_way2.Text = '方式2';
            app.Node_svg = uitreenode(app.Node_way2);
            app.Node_svg.Text = 'svg路径图像（导出svg）';
            app.Node_picPa = uitreenode(app.Node_way2);
            app.Node_picPa.Text = '参数表格（导出csv/mat）';
            app.Node_fft = uitreenode(app.Node_way2);
            app.Node_fft.Text = '傅里叶系数（导出txt）';
            app.Node_audioFFT = uitreenode(app.Tree_1);
            app.Node_audioFFT.Text = '音频FFT';
            app.Node_audioWave = uitreenode(app.Node_audioFFT);
            app.Node_audioWave.Text = '波形图（导出png）';
            app.Node_audioSpectrum = uitreenode(app.Node_audioFFT);
            app.Node_audioSpectrum.Text = '频谱图（导出png）';
            app.Node_instrument = uitreenode(app.Node_audioFFT);
            app.Node_instrument.Text = '乐器谐波图像（导出png）';
            app.Node_ligntArduino = uitreenode(app.Tree_1);
            app.Node_ligntArduino.Text = 'arduino光信号处理';
            app.Node_ligntPa = uitreenode(app.Node_ligntArduino);
            app.Node_ligntPa.Text = '参数表格（导出csv/mat）';
            app.Node_lightWave = uitreenode(app.Node_ligntArduino);
            app.Node_lightWave.Text = '波形图（导出png）';
            app.Node_lightSpectrum = uitreenode(app.Node_ligntArduino);
            app.Node_lightSpectrum.Text = '频谱图（导出png）';
            app.Tree_2 = uitree(app.GridLayout_dataOutput, 'checkbox');
            app.Node_soundArduino = uitreenode(app.Tree_2);
            app.Node_soundArduino.Text = 'arduino声信号处理';
            app.Node_arduino = uitreenode(app.Node_soundArduino);
            app.Node_arduino.Text = 'arduino接口';
            app.Node_arduinoPa = uitreenode(app.Node_arduino);
            app.Node_arduinoPa.Text = '参数表格（导出csv/mat）';
            app.Node_threshold = uitreenode(app.Node_arduino);
            app.Node_threshold.Text = '采集最低值参数（导出mat）';
            app.Node_ardWave = uitreenode(app.Node_arduino);
            app.Node_ardWave.Text = '波形图（导出png）';
            app.Node_ardSpectrum = uitreenode(app.Node_arduino);
            app.Node_ardSpectrum.Text = '频谱图（导出png）';
            app.Node_silence = uitreenode(app.Node_arduino);
            app.Node_silence.Text = '静音检测图像（导出png）';
            app.Node_origin = uitreenode(app.Node_arduino);
            app.Node_origin.Text = '变声处理原波形图（导出png）';
            app.Node_processed = uitreenode(app.Node_arduino);
            app.Node_processed.Text = '变声处理加工波形图（导出png）';
            app.Node_microphone = uitreenode(app.Node_soundArduino);
            app.Node_microphone.Text = '麦克风接口';
            app.Node_micWave = uitreenode(app.Node_microphone);
            app.Node_micWave.Text = '波形图（导出png）';
            app.Node_micSpectrum = uitreenode(app.Node_microphone);
            app.Node_micSpectrum.Text = '频谱图（导出png）';
            app.Node_micOrigin = uitreenode(app.Node_microphone);
            app.Node_micOrigin.Text = '变声处理原波形图（导出png）';
            app.Node_micProcessed = uitreenode(app.Node_microphone);
            app.Node_micProcessed.Text = '变声处理加工波形图（导出png）';
            app.Tree_3 = uitree(app.GridLayout_dataOutput, 'checkbox');
            app.Node_musicGame = uitreenode(app.Tree_3);
            app.Node_musicGame.Text = '实时音游';
            app.Node_mgSpectrum = uitreenode(app.Node_musicGame);
            app.Node_mgSpectrum.Text = '频谱图（导出png）';
            app.Node_score = uitreenode(app.Node_musicGame);
            app.Node_score.Text = '得分（导出mat）';
            app.Node_practice = uitreenode(app.Tree_3);
            app.Node_practice.Text = '交互练习';
            app.Node_analysis = uitreenode(app.Node_practice);
            app.Node_analysis.Text = '题目解析（导出txt）';
            app.Panel_data = uipanel(app.GridLayout_dataOutput);
            app.GridLayout_data = uigridlayout(app.Panel_data);
            app.Button_exportData = uibutton(app.GridLayout_data, 'push');
            app.Button_exportData.ButtonPushedFcn = createCallbackFcn(app, @Button_exportDataPushed, true);
            app.Button_exportData.Text = '导出数据';
            app.Image_self = uiimage(app.GridLayout_data);
            app.Image_self.Tooltip = {'App作者'};
            app.Image_self.ImageSource = fullfile(pathToMLAPP, 'pictures', 'self.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFT_Serial_Communication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end